classdef NLX_Event_Finder_AT_v8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLXEventFinderUIFigure       matlab.ui.Figure
        SessCreate                   matlab.ui.control.Button
        STAGE2CreateSessionLOGPanel  matlab.ui.container.Panel
        ACCEPTSwitch                 matlab.ui.control.Switch
        ACCEPTSwitchLabel            matlab.ui.control.Label
        SessionTypeButtonGroup       matlab.ui.container.ButtonGroup
        RestingstateButton           matlab.ui.control.RadioButton
        BehaviorButton               matlab.ui.control.RadioButton
        LOGVIEW                      matlab.ui.container.Panel
        NextEVEn                     matlab.ui.control.Button
        curEvbutton                  matlab.ui.container.ButtonGroup
        SkipEv                       matlab.ui.control.ToggleButton
        ProcEv                       matlab.ui.control.ToggleButton
        Panel                        matlab.ui.container.Panel
        foldCREATE                   matlab.ui.control.Button
        closeProg                    matlab.ui.control.Button
        ExpSess                      matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        EventList                    matlab.ui.control.ListBox
        EventListListBoxLabel        matlab.ui.control.Label
        ContextMain                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
        ContextEditMessage           matlab.ui.container.ContextMenu
        Back2top                     matlab.ui.container.Menu
        Clear                        matlab.ui.container.Menu
    end


    properties (Access = private)
        eventLoc % Location for event files
        nevLIST
        timestamps
        ttl
        header
        evText
        EventDat
        maxRec
        defaultDet
        SaveSession
        saveSessCount
        sessSaveLoc
        eventLogtab
        LogTasks
        useEvList
    end

    %%%% TO DO
    %% 4/2/2021
    % Stage 2
    % In procesEventFile
    %    1. Add a feature to determine stage - during stage 2
    %    2. ADD ability to indicate session type in table


    %%%%% STEPS
    % 1. Load folders - Raw and Save
    % 2. Search for Log file
    % 3. If not created
    % 4. Cycle through files and create
    % 5. Save and move to next step
    % 6. If Log file created
    % 7. Show available Event files in List


    methods (Access = private)

        function processEventFile(app)

            % 1. Determine number of Start Recording fields
            startFields = contains(app.EventDat.tInfo,'Starting Recording');

            numRecords = sum(startFields);
            startRecInds = find(startFields);
            if sum(startFields) == 0 && any(contains(app.EventDat.tInfo,'ttl'))
                numRecords = 1;
                startRecInds = find(matches(app.EventDat.tInfo,'ttl'),1,'first');
            end
            

            % 2. Determine number of TTL flags
            
            numEventFlags = zeros(size(startRecInds));
            timeDUr = zeros(size(startRecInds));
            for ttlIbtw = 1:numRecords
                if ttlIbtw == numRecords % This is the last Start Recording file
                    numEventFlags(ttlIbtw) = numel(app.EventDat.tInfo(startRecInds(ttlIbtw)+1:end));
                    timeDUr(ttlIbtw) = (app.EventDat.ts(end) - app.EventDat.ts(startRecInds(ttlIbtw)))/1000000;
                else
                    numEventFlags(ttlIbtw) = (startRecInds(ttlIbtw + 1) - startRecInds(ttlIbtw)) - 1;
                    timeDUr(ttlIbtw) = (app.EventDat.ts(startRecInds(ttlIbtw + 1)) - app.EventDat.ts(startRecInds(ttlIbtw)))/1000000;
                end
            end

            % Clean up with respect to duration value - if negative remove
            startRecIuse = startRecInds(timeDUr > 0);
            eventsCountuse = numEventFlags(timeDUr > 0);
            timeDurUse = timeDUr(timeDUr > 0);
            timedurMins = round(timeDurUse/60,1);
            RecNum = transpose(1:numel(startRecIuse));
            app.maxRec = max(RecNum);

            % Populate table
            varNames = {'Record#','STARTIndex','NumEvents','DurMins'};
            if isempty(RecNum)
                RecNum = 0;
            end
            if isempty(startRecIuse)
                startRecIuse = 0;
            end
            if isempty(eventsCountuse)
                eventsCountuse = 0;
            end
            if isempty(timedurMins)
                timedurMins = 0;
            end

            app.UITable.Data = array2table([RecNum , startRecIuse , eventsCountuse, timedurMins],...
                'VariableNames',varNames);

            app.UITable.ColumnName = varNames;

        end

        function closeFunction(app)
            selection = uiconfirm(app.NLXEventFinderUIFigure,'Close PROGRAM?',...
                'Confirmation');

            switch selection
                case 'OK'
                    delete(app.NLXEventFinderUIFigure)

                case 'Cancel'
                    return
            end

        end

        function matdateStr = convertUNIXtime(~, uniXTimes) % '~' is placeholder for app

            matdateStr = NaT(size(uniXTimes),'TimeZone','America/Denver');
            for ci = 1:length(uniXTimes)
                timeREcord = datetime(uniXTimes(ci)/1000000,'ConvertFrom',...
                    'posixtime','TimeZone','America/Denver');
                matdateStr(ci) = timeREcord;
            end

        end

        function processLOGview(app , LOGind)

            curEvent = app.eventLogtab.EventFnames{LOGind};

            [Timestamps, ~, TTLs, ~, evMessage, evHeader] =...
                Nlx2MatEV(curEvent, [1 1 1 1 1], 1, 1, [] );

            % Extract timestamps, TTLs, and header
            app.timestamps = Timestamps;
            app.ttl = TTLs;
            app.header = evHeader;
            app.evText = evMessage;

            app.EventDat.ts = app.timestamps;
            app.EventDat.ttl = app.ttl;
            app.EventDat.header = app.header;
            app.EventDat.tInfo = app.evText;

            % ADD CHECK
            if ((length(app.timestamps) == 1) && app.timestamps == 0) ||...
                    ~(any(contains(app.evText, {'Starting Recording','ttl'})))

                % Auto advance and populate table
                % 1. Get current log row
                curLOGev = app.LogTasks.logInds(app.LogTasks.curNum);
                % 2. Get decision
                decision = 'NotUsable';
                viewFFile = false;
                logView = true;

                app.eventLogtab.ViewFile(curLOGev) = viewFFile;
                app.eventLogtab.ReasonNotV{curLOGev} = decision;
                app.eventLogtab.LOGviewed(curLOGev) = logView;
                app.SkipEv.Value = 1;
                app.ProcEv.Value = 0;

                % 3. Advance
                startRecIuse = [0;0];
                eventsCountuse = [0;0];
                %                 timeDurUse = [0;0];
                timedurMins = [0;0];
                RecNum = [0;0];

                % Populate table
                varNames = {'Record#','STARTIndex','NumEvents','DurMins'};
                app.UITable.Data = array2table([RecNum , startRecIuse , eventsCountuse,timedurMins],...
                    'VariableNames',varNames);

            else

                curLOGev = app.LogTasks.logInds(app.LogTasks.curNum);
                app.eventLogtab.ViewFile(curLOGev) = true;
                app.eventLogtab.ReasonNotV{curLOGev} = '';
                app.eventLogtab.LOGviewed(curLOGev) = true;
                app.SkipEv.Value = 0;
                app.ProcEv.Value = 1;

                processEventFile(app)

            end

        end


        function createLOGtable(app)
            EventFnames = transpose(app.nevLIST);
            ViewFile = ones(size(EventFnames),'logical');
            ReasonNotV = repmat({''},length(EventFnames),1);
            LOGviewed = zeros(size(EventFnames),'logical');
            NeuroEvents = repmat({''},length(EventFnames),1);
            DateRecord = NaT(size(EventFnames),'TimeZone','America/Denver');
            StartTime = NaT(size(EventFnames),'TimeZone','America/Denver');
            app.eventLogtab = table(EventFnames,ViewFile,ReasonNotV,LOGviewed,NeuroEvents,DateRecord,StartTime);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            currentLOC = which('NLX_Event_Finder_v8');
            filePARts = split(currentLOC,'\');
            newLOC = join(filePARts(1:numel(filePARts)-1),'\');
            resourceLOC = [newLOC{1}, filesep, 'NLX_IO_Code'];
            addpath(genpath(resourceLOC));

        end

        % Value changed function: EventList
        function EventListValueChanged(app, event)


            curEvent = app.EventList.Value;
            % Check which Event file the user clicked on
            if strcmp(curEvent,'...')
                % If the first value than return
                return
            else
                % If an available event file, then load using NLX reader
                [Timestamps, ~, TTLs, ~, evMessage, evHeader] =...
                    Nlx2MatEV(curEvent, [1 1 1 1 1], 1, 1, [] );

                % Extract timestamps, TTLs, and header
                app.timestamps = Timestamps;
                app.ttl = TTLs;
                app.header = evHeader;
                app.evText = evMessage;

                app.EventDat.ts = app.timestamps;
                app.EventDat.ttl = app.ttl;
                app.EventDat.header = app.header;
                app.EventDat.tInfo = app.evText;

            end

            app.ACCEPTSwitch.Enable = 'on';
            app.ACCEPTSwitch.Value = 'Off';
            app.STAGE2CreateSessionLOGPanel.Enable = 'on';

            % Extract start time and date
            % Update Time / Date - use first time stamp
            tmpDate1 = convertUNIXtime(app,Timestamps(1));

            % Find event in table
            eventIND = contains(app.eventLogtab.EventFnames, curEvent);
            app.eventLogtab.DateRecord(eventIND) = tmpDate1;

            if ((length(app.timestamps) == 1) && app.timestamps == 0) ||...
                    ~(any(contains(app.evText, {'Starting Recording','ttl'})))
                uialert(app.NLXEventFinderUIFigure, 'NO Timestamps!',...
                    "Timestamp Acquisition", "Icon","warning")
            else
                % Count number of timestamps
                processEventFile(app)
                %                 app.SessCreate.Enable = 'on';
                app.STAGE2CreateSessionLOGPanel.Enable = 'on';


                % UPDATE spinner


            end

        end

        % Menu selected function: Back2top
        function Back2topMenuSelected(app, event)
            scroll(app.statusMess, 'top')
        end

        % Menu selected function: Clear
        function ClearMenuSelected(app, event)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Callback function
        function RecordSelValueChanged(app, event)
            value = app.RecordSel.Value;

            if value > app.maxRec
                app.RecordSel.Value = app.maxRec;
                return
            end

        end

        % Button pushed function: SessCreate
        function SessCreateButtonPushed(app, event)

            if strcmp(app.ACCEPTSwitch.Value,'On')
                % Loop through sessions

                sessNUM = length(app.UITable.Data.("Record#"));

                sessSel = app.SessionTypeButtonGroup.SelectedObject.Text;

                tmpSession = struct;
                for si = 1:sessNUM

                    numEVents = app.UITable.Data.NumEvents(si);
                    tmpStartI = app.UITable.Data.STARTIndex(si);
                    if numEVents == 1
                        tmpStopI =  (tmpStartI+numEVents);
                    else
                        tmpStopI =  (tmpStartI+numEVents)-1;
                    end
                    % Populate defaults

                    % Extract data

                    % Check Start index

                    if matches(app.EventDat.tInfo{tmpStartI},'Starting Recording')
                        tmpSession.StartIndex(si) = tmpStartI;
                    elseif ~any(contains(app.EventDat.tInfo,'Starting Recording'))
                        tmpSession.StartIndex(si) = tmpStartI;
                    else
                        continue
                    end

                    stopCheck1 = ~matches(app.EventDat.tInfo{tmpStopI},'Starting Recording');
                    stopCheck2 = tmpStopI <= length(app.EventDat.ts);

                    if stopCheck1 && stopCheck2
                        tmpSession.StopIndex(si)  = tmpStopI;
                    else
                        continue
                    end

                    %                     tmpSession.StartTime(si)  = app.EventDat.ts(tmpStartI) - 2*1000000;
                    %                     tmpSession.StopTime(si)   = app.EventDat.ts(tmpStopI) + 2*1000000;
                    tmpSession.StartTime(si)  = app.EventDat.ts(tmpStartI);
                    tmpSession.StopTime(si)   = app.EventDat.ts(tmpStopI);
                    tmpSession.StartStr{si}   = app.EventDat.tInfo{tmpStartI};
                    tmpSession.StopStr{si}    = app.EventDat.tInfo{tmpStopI};

                end

                % Clean up missing data
                remOVEind = cellfun(@(x) ~isempty(x), tmpSession.StartStr, 'UniformOutput',true);
                if ~all(remOVEind)
                    tmpSession.StartIndex = tmpSession.StartIndex(remOVEind);
                    tmpSession.StopIndex = tmpSession.StopIndex(remOVEind);
                    tmpSession.StartTime = tmpSession.StartTime(remOVEind);
                    tmpSession.StopTime = tmpSession.StopTime(remOVEind);
                    tmpSession.StartStr = tmpSession.StartStr(remOVEind);
                    tmpSession.StopStr = tmpSession.StopStr(remOVEind);
                end

                % All other information
                tmpSession.SessionInfo = app.EventDat;
                tmpSession.SessType = sessSel;
                tmpSession.DateRec = convertUNIXtime(app, app.EventDat.ts(1));

                app.SaveSession{app.saveSessCount} = tmpSession;
                app.saveSessCount = app.saveSessCount + 1;

                % Update Table
                curEvent = app.EventList.Value;
                eventIND = contains(app.eventLogtab.EventFnames, curEvent);
                tmpDate1 = convertUNIXtime(app,app.timestamps(1));
                app.eventLogtab.DateRecord(eventIND) = tmpDate1;
                app.eventLogtab.ViewFile(eventIND) = false;
                app.eventLogtab.ReasonNotV{eventIND} = sessSel;

                % Save table
                cd(app.eventLoc)
                eventTtable = app.eventLogtab;
                save("EventLogTracker.mat","eventTtable")

                % Remove from Event List
                cureventLIST = app.EventList.Items;
                app.EventList.Items =  cureventLIST(~contains(cureventLIST,curEvent));

                app.ExpSess.Enable = 'on';

                % Re-enable Event List and Session Info and Clear Session
                % Info
                app.EventList.Enable = 'on';
                app.STAGE2CreateSessionLOGPanel.Enable = 'on';

                app.ACCEPTSwitch.Value = 'Off';

                % Clear table values
                app.UITable.Data = [];
                app.ACCEPTSwitch.Enable = 'off';

                app.SessCreate.Enable = 'off';

            elseif strcmp(app.ACCEPTSwitch.Value,'Off')
                return
            end


        end

        % Button pushed function: closeProg
        function closeProgButtonPushed(app, event)
            closeFunction(app)
        end

        % Button pushed function: NextEVEn
        function NextEVEnButtonPushed(app, event)

            % Update LogEvent Table

            % 1. Get current log row
            curLOGev = app.LogTasks.logInds(app.LogTasks.curNum);
            % 2. Get decision
            if app.SkipEv.Value
                decision = 'NotUsable';
                viewFFile = false;
                neuroE = 'NotUsable';
            elseif app.ProcEv.Value
                decision = '';
                viewFFile = true;
                neuroE = '';
            end
            logView = true;

            app.eventLogtab.ViewFile(curLOGev) = viewFFile;
            app.eventLogtab.ReasonNotV{curLOGev} = decision;
            app.eventLogtab.LOGviewed(curLOGev) = logView;
            app.eventLogtab.NeuroEvents{curLOGev} = neuroE;

            % Determine if this is the last file
            if curLOGev == app.LogTasks.totalN
                % UICONFIRM that Log viewing is done
                doneck = uiconfirm(app.NLXEventFinderUIFigure, 'LOG VIEWing Complete',...
                    'You are done checking LOG files','Options',...
                    {'OK','Cancel'});
                switch doneck
                    case {'OK','Cancel'}

                        % TURN off Log view
                        app.LOGVIEW.Enable = 'off';
                        % TURN event list - populate with relevant event files
                        app.EventList.Enable = 'on';
                        app.useEvList = app.nevLIST(app.eventLogtab.ViewFile);
                        % Clean up NEVLIST based on ViewFile table
                        app.EventList.Items = ['...' , app.useEvList];

                end

                % SAVE out Event file log
                cd(app.eventLoc)
                eventTtable = app.eventLogtab;
                save("EventLogTracker.mat","eventTtable")

            else
                % Advance number and Log view
                app.LogTasks.curNum = app.LogTasks.curNum + 1;
                processLOGview(app , app.LogTasks.logInds(app.LogTasks.curNum))
                app.curEvbutton.Title = ['CURRENT FILE: ' ,...
                    app.eventLogtab.EventFnames{app.LogTasks.logInds(app.LogTasks.curNum)}];

            end

        end

        % Button pushed function: ExpSess
        function ExpSessButtonPushed(app, event)

            % Check if this is the last EVENT
            if matches(app.EventList.Items,'...') && numel(app.EventList.Items) == 1

                cd(app.sessSaveLoc)
                % clean up app.SaveSession
                saveSessAll1 = app.SaveSession(cellfun(@(x) ~isempty(x),...
                    app.SaveSession, 'UniformOutput',true));

                % Session file info
                eventSaveLoc = app.eventLoc;
                sessionSaveLoc = app.sessSaveLoc;

                % Split Multi sessions for individual Event files
                saveSessSplit = cell(length(saveSessAll1)*3,1); % assume each struct will be split 3 times
                nC = 1;
                for si = 1:length(saveSessAll1)

                    tmpSes = saveSessAll1{si};

                    if length(tmpSes.StartIndex) == 1
                        saveSessSplit{nC} = tmpSes;
                        nC = nC + 1;
                    else
                        for si2 = 1:length(tmpSes.StartIndex)


                            if tmpSes.StopIndex(si2) - tmpSes.StartIndex(si2) < 0
                                continue
                            else


                                saveSessSplit{nC}.StartIndex = tmpSes.StartIndex(si2);
                                saveSessSplit{nC}.StopIndex = tmpSes.StopIndex(si2);
                                saveSessSplit{nC}.StartTime = tmpSes.StartTime(si2);
                                saveSessSplit{nC}.StopTime = tmpSes.StopTime(si2);
                                saveSessSplit{nC}.StartStr = tmpSes.StartStr{si2};
                                saveSessSplit{nC}.StopStr = tmpSes.StopStr{si2};
                                saveSessSplit{nC}.SessionInfo = tmpSes.SessionInfo;
                                saveSessSplit{nC}.SessType = tmpSes.SessType;
                                saveSessSplit{nC}.DateRec = tmpSes.DateRec;
                                nC = nC + 1;
                            end
                        end
                    end
                end

                % clean up app.SaveSession part 2
                saveSessAll = saveSessSplit(cellfun(@(x) ~isempty(x),...
                    saveSessSplit, 'UniformOutput',true));

                % Subject ID
                prompt = 'Subject ID';
                dlgtitle = 'SUBJECT';
                dims = [1 35];
                definput = {'SUBJECT_ID'};
                subID = inputdlg(prompt,dlgtitle,dims,definput);

                % Latest save
                tNOW = datestr(datetime('now'));
                tNOWrd = strrep(tNOW,'-','');
                tNOWfi = strrep(tNOWrd,' ','_');

                % Session INFO
                sessionINFo.eventLOC = eventSaveLoc;
                sessionINFo.sessiLOC = sessionSaveLoc;
                sessionINFo.SUBid = subID;
                sessionINFo.Created = tNOWfi;

                % Save data
                save("allSessionData.mat","saveSessAll","sessionINFo");

                % Save table
                cd(app.eventLoc)
                eventTtable = app.eventLogtab;
                save("EventLogTracker.mat","eventTtable");

            else
                return
            end

        end

        % Value changed function: ACCEPTSwitch
        function ACCEPTSwitchValueChanged(app, event)
            value = app.ACCEPTSwitch.Value;

            switch value
                case 'On'
                    app.STAGE2CreateSessionLOGPanel.Enable = 'off';
                    app.EventList.Enable = 'off';
                    app.SessCreate.Enable = 'on';
                case 'Off'
                    app.STAGE2CreateSessionLOGPanel.Enable = 'on';
                    app.SessCreate.Enable = 'off';
            end


        end

        % Button pushed function: foldCREATE
        function foldCREATEButtonPushed(app, event)

            % User Locates a subject folder .mat setFold file
            % UICONFIRM PROMPT TO SELECT RAW FOLDER
            rawSEL = uiconfirm(app.NLXEventFinderUIFigure, 'SELECT RAW Event Folder',...
                "RAW Event Location", "Icon","info");

            switch rawSEL
                case 'OK'
                    nevCheck = 1;
                    while nevCheck
                        [app.eventLoc] = uigetdir('title','Select folder with Events file');

                        if app.eventLoc == 0
                            ckCan = uiconfirm(app.NLXEventFinderUIFigure, 'DID you mean to CANCEL?',...
                                "NO Selection?", "Icon","info");

                            switch ckCan
                                case 'OK'
                                    return
                                case 'Cancel'
                                    continue
                            end

                        else
                            cd(app.eventLoc)
                        end

                        nevDIRa = dir("*.nev");
                        nevDIRb = {nevDIRa.name};

                        if isempty(nevDIRb)
                            continue
                        else
                            nevCheck = 0;
                        end
                    end

                case 'Cancel'
                    return

            end


            % Check one folder up for a startUPfold.mat file
            eventLOCparts = split(app.eventLoc,filesep);
            oneFoldback = strjoin(eventLOCparts(1:end-1),filesep);

            % Cd to one folder back
            cd(oneFoldback)
            % Check if .mat file is present
            matCheck = dir('*.mat');
            matCheckn = {matCheck.name};
            if isempty(matCheckn) || ~matches('NWB_Process_Folder_Check.mat',matCheckn)
                sessSavLoc = [oneFoldback , filesep , 'Session_Data'];
                nwbSLoc = [oneFoldback , filesep , 'NWB_Data'];
                mkdir(sessSavLoc)
                mkdir(nwbSLoc)
                nwbFilesLOCS.sessSAVloc = sessSavLoc;
                nwbFilesLOCS.nwbSAVloc = nwbSLoc;
                nwbFilesLOCS.eventLOC = app.eventLoc;
                save('NWB_Process_Folder_Check.mat','nwbFilesLOCS')
            else
                load('NWB_Process_Folder_Check.mat','nwbFilesLOCS')
            end

            % Save Session Loc
            app.sessSaveLoc = nwbFilesLOCS.sessSAVloc;

            % DETERMINE IF THERE ARE PREVIOUSLY SAVED Sessions
            cd(app.eventLoc)
            logDirA = dir('*.mat');
            logDirB = {logDirA.name};

            % Check if any mat present
            if isempty(logDirB)
                matPresck = 1;
                matNameck = 1;
            else
                matPresck = 0;
                % Check if file name is correct
                if contains(logDirB,'EventLogTracker')
                    matNameck = 0;
                else
                    matNameck = 1;
                end
            end

            % Both the file is present and has the correct name
            if ~matNameck && ~matPresck
                load("EventLogTracker.mat","eventTtable")
                app.eventLogtab = eventTtable;

                app.saveSessCount = 1;

            elseif matNameck || matPresck
                uialert(app.NLXEventFinderUIFigure, 'NO Event LOG file',...
                    "NO Event Log", "Icon","warning")

                %                 uialert(app.NLXEventFinderUIFigure,'Starting STAGE 1',...
                %                     "STAGE 1", "Icon","info")

                app.nevLIST = nevDIRb;

                app.saveSessCount = 1;

                % Create Event log file and save
                createLOGtable(app)

            end

            % Determine if LOG View should be used
            if ~any(app.eventLogtab.LOGviewed)

                app.LOGVIEW.Enable = 'on';

                logIndices = find(~app.eventLogtab.LOGviewed);
                app.LogTasks.logInds = logIndices;

                app.LogTasks.curNum = 1;
                app.LogTasks.totalN = numel(logIndices);
                processLOGview(app , logIndices(1))
                app.curEvbutton.Title = ['CURRENT FILE: ' ,...
                    app.eventLogtab.EventFnames{logIndices(app.LogTasks.curNum)}];

            else % ACTIVATE REST OF GUI

                app.EventList.Enable = 'on';
                app.nevLIST = nevDIRb;
                app.useEvList = app.nevLIST(app.eventLogtab.ViewFile);
                % Clean up NEVLIST based on ViewFile table
                app.EventList.Items = ['...' , app.useEvList];

            end

            app.foldCREATE.Enable = 'off';
            app.ExpSess.Enable = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLXEventFinderUIFigure = uifigure('Visible', 'off');
            app.NLXEventFinderUIFigure.Name = 'NLX Event Finder';
            app.NLXEventFinderUIFigure.Icon = 'icon.png';
            app.EventListListBoxLabel = uilabel(app.NLXEventFinderUIFigure);
            app.EventListListBoxLabel.Text = 'Event List';
            app.EventList = uilistbox(app.NLXEventFinderUIFigure);
            app.EventList.Items = {'...'};
            app.EventList.ValueChangedFcn = createCallbackFcn(app, @EventListValueChanged, true);
            app.EventList.Enable = 'off';
            app.EventList.Value = '...';
            app.UITable = uitable(app.NLXEventFinderUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Panel = uipanel(app.NLXEventFinderUIFigure);
            app.ExpSess = uibutton(app.Panel, 'push');
            app.ExpSess.ButtonPushedFcn = createCallbackFcn(app, @ExpSessButtonPushed, true);
            app.ExpSess.Icon = 'exportButton.png';
            app.ExpSess.Enable = 'off';
            app.ExpSess.Text = '';
            app.closeProg = uibutton(app.Panel, 'push');
            app.closeProg.ButtonPushedFcn = createCallbackFcn(app, @closeProgButtonPushed, true);
            app.closeProg.Icon = 'closeButton.png';
            app.closeProg.Text = '';
            app.foldCREATE = uibutton(app.Panel, 'push');
            app.foldCREATE.ButtonPushedFcn = createCallbackFcn(app, @foldCREATEButtonPushed, true);
            app.foldCREATE.Icon = 'FolderCREATE.png';
            app.foldCREATE.Text = '';
            app.LOGVIEW = uipanel(app.NLXEventFinderUIFigure);
            app.LOGVIEW.Enable = 'off';
            app.LOGVIEW.Title = 'STAGE 1: Create Event LOG';
            app.curEvbutton = uibuttongroup(app.LOGVIEW);
            app.curEvbutton.Title = 'CURRENT EVENT FILE: ';
            app.ProcEv = uitogglebutton(app.curEvbutton);
            app.ProcEv.Text = 'PROCESS';
            app.ProcEv.Value = true;
            app.SkipEv = uitogglebutton(app.curEvbutton);
            app.SkipEv.Text = 'SKIP';
            app.NextEVEn = uibutton(app.LOGVIEW, 'push');
            app.NextEVEn.ButtonPushedFcn = createCallbackFcn(app, @NextEVEnButtonPushed, true);
            app.NextEVEn.Text = 'NEXT EVENT -->';
            app.STAGE2CreateSessionLOGPanel = uipanel(app.NLXEventFinderUIFigure);
            app.STAGE2CreateSessionLOGPanel.Enable = 'off';
            app.STAGE2CreateSessionLOGPanel.Title = 'STAGE 2: Create Session LOG';
            app.SessionTypeButtonGroup = uibuttongroup(app.STAGE2CreateSessionLOGPanel);
            app.SessionTypeButtonGroup.Title = 'Session Type';
            app.BehaviorButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.BehaviorButton.Text = 'Behavior';
            app.BehaviorButton.Value = true;
            app.RestingstateButton = uiradiobutton(app.SessionTypeButtonGroup);
            app.RestingstateButton.Text = 'Resting state';
            app.ACCEPTSwitchLabel = uilabel(app.STAGE2CreateSessionLOGPanel);
            app.ACCEPTSwitchLabel.Text = 'ACCEPT';
            app.ACCEPTSwitch = uiswitch(app.STAGE2CreateSessionLOGPanel, 'slider');
            app.ACCEPTSwitch.ValueChangedFcn = createCallbackFcn(app, @ACCEPTSwitchValueChanged, true);
            app.SessCreate = uibutton(app.NLXEventFinderUIFigure, 'push');
            app.SessCreate.ButtonPushedFcn = createCallbackFcn(app, @SessCreateButtonPushed, true);
            app.SessCreate.Icon = 'createSession.png';
            app.SessCreate.Enable = 'off';
            app.SessCreate.Text = '';
            app.ContextMain = uicontextmenu(app.NLXEventFinderUIFigure);
            app.Menu = uimenu(app.ContextMain);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMain);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMain
            app.NLXEventFinderUIFigure.ContextMenu = app.ContextMain;
            app.ContextEditMessage = uicontextmenu(app.NLXEventFinderUIFigure);
            app.Back2top = uimenu(app.ContextEditMessage);
            app.Back2top.MenuSelectedFcn = createCallbackFcn(app, @Back2topMenuSelected, true);
            app.Back2top.Text = 'Back to top';
            app.Clear = uimenu(app.ContextEditMessage);
            app.Clear.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.Clear.Text = 'Clear all';
            % Show the figure after all components are created
            app.NLXEventFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_Event_Finder_AT_v8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLXEventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLXEventFinderUIFigure)
        end
    end
end