classdef NLX_SessionFix_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLXEventFinderUIFigure      matlab.ui.Figure
        SessionSplit                matlab.ui.container.Panel
        SPLIT                       matlab.ui.control.Button
        SessChange                  matlab.ui.control.Table
        ACCEPTButton                matlab.ui.control.StateButton
        SessionNUM                  matlab.ui.control.Spinner
        NumberSessionsSpinnerLabel  matlab.ui.control.Label
        MENU                        matlab.ui.container.Panel
        ExpSess                     matlab.ui.control.Button
        foldCREATE                  matlab.ui.control.Button
        closeProg                   matlab.ui.control.Button
        ProcSess                    matlab.ui.control.Button
        SessionNEW                  matlab.ui.container.Panel
        SessTable                   matlab.ui.control.Table
        SessList                    matlab.ui.control.ListBox
        SessionListLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        eventLoc % Location for event files
        nevLIST
        timestamps
        ttl
        header
        evText
        EventDat
        maxRec
        defaultDet
        SaveSession
        saveSessCount
        sessSaveLoc
        eventLogtab
        LogTasks
        useEvList

        % Added from update
        locsFILE
        mainLoc
        sessLoc
        nwbLoc
        sessLOC
        nwbLOC
        sessFILE
        sessLOGdat
        sessINFO

        curSESSold
        curSESSnew

        allSESSold
        allSESSnew

    end

    %%%% TO DO
    %% 8/10/2021
    % 1. When SET is hit
    %    a. New Session is populated with appropriate data
    % 2. When SPLIT Button is hit
    %    a. Session Split is cleared
    %    b. New Session is cleared
    %    c. Data is stored in new curSESSnew/allSESSnew
    %    d. allSESSold is a logical to determine if has been processed
    %    e. Session list is brought back to '...'
    % 3. When Session selected in List
    %    a. If not processed session - same as always [check allSESSold]
    %    b. If processed [check allSESSold (logical) / allSESSnew (cell)]
    %       i. PROCESS SESSION - disenable
    %       ii. New Session Table populated with new data


    methods (Access = private)


        function processEventFile(app)

            % 1. Determine number of Start Recording fields
            startFields = contains(app.EventDat.tInfo,'Starting Recording');
            numRecords = sum(startFields);

            % 2. Determine number of TTL flags
            startRecInds = find(startFields);
            numEventFlags = zeros(size(startRecInds));
            timeDUr = zeros(size(startRecInds));
            for ttlIbtw = 1:numRecords
                if ttlIbtw == numRecords % This is the last Start Recording file
                    numEventFlags(ttlIbtw) = numel(app.EventDat.tInfo(startRecInds(ttlIbtw)+1:end));
                    timeDUr(ttlIbtw) = (app.EventDat.ts(end) - app.EventDat.ts(startRecInds(ttlIbtw)))/1000000;
                else
                    numEventFlags(ttlIbtw) = (startRecInds(ttlIbtw + 1) - startRecInds(ttlIbtw)) - 1;
                    timeDUr(ttlIbtw) = (app.EventDat.ts(startRecInds(ttlIbtw + 1)) - app.EventDat.ts(startRecInds(ttlIbtw)))/1000000;
                end
            end

            % Clean up with respect to duration value - if negative remove
            startRecIuse = startRecInds(timeDUr > 0);
            eventsCountuse = numEventFlags(timeDUr > 0);
            timeDurUse = timeDUr(timeDUr > 0);
            timedurMins = round(timeDurUse/60,1);
            RecNum = transpose(1:numel(startRecIuse));
            app.maxRec = max(RecNum);

            % Populate table
            varNames = {'Session#','Records','Index','DurMins','NumEvents'};
            app.SessTable.Data = array2table([RecNum , startRecIuse , eventsCountuse, round(timeDurUse),timedurMins],...
                'VariableNames',varNames);

            app.SessTable.ColumnName = varNames;

            app.RecordSel.Limits = [1 max(RecNum) + 1];

            % Preemptively update session stop index
            app.StopIndexSpinner.Value = numel(app.EventDat.ts);

        end

        function closeFunction(app)
            selection = uiconfirm(app.NLXEventFinderUIFigure,'Close PROGRAM?',...
                'Confirmation');

            switch selection
                case 'OK'
                    delete(app.NLXEventFinderUIFigure)

                case 'Cancel'
                    return
            end

        end

        function matdateStr = convertUNIXtime(~, uniXTimes) % '~' is placeholder for app

            matdateStr = NaT(size(uniXTimes),'TimeZone','America/Denver');
            for ci = 1:length(uniXTimes)
                timeREcord = datetime(uniXTimes(ci)/1000000,'ConvertFrom',...
                    'posixtime','TimeZone','America/Denver');
                matdateStr(ci) = timeREcord;
            end

        end

        function updateLOGVIEW(app)
            tmpDate1 = convertUNIXtime(app,app.timestamps(1));

            dayDate = datestr(tmpDate1,'mm/dd/yyyy');
            timeDate = datestr(tmpDate1,"HH:MM:SS");

            app.dateField.Value = dayDate;
            app.startTfield.Value = timeDate;

            % Update message with load and availability of TTLs
            updateMessage(app , ['Date: ' , app.dateField.Value])
            updateMessage(app , ['Start Time: ' , app.startTfield.Value])

            % Count number of timestamps
            numberTSs = num2str(length(app.timestamps));
            % Update message
            updateMessage(app , ['Number of timestamps: ', numberTSs])
        end

        function createLOGtable(app)
            EventFnames = transpose(app.nevLIST);
            ViewFile = ones(size(EventFnames),'logical');
            ReasonNotV = repmat({''},length(EventFnames),1);
            LOGviewed = zeros(size(EventFnames),'logical');
            NeuroEvents = repmat({''},length(EventFnames),1);
            DateRecord = NaT(size(EventFnames),'TimeZone','America/Denver');
            StartTime = NaT(size(EventFnames),'TimeZone','America/Denver');
            app.eventLogtab = table(EventFnames,ViewFile,ReasonNotV,LOGviewed,NeuroEvents,DateRecord,StartTime);
        end
        
        function updateSESSchange(app)

            fIRST = str2double(extract(app.SessList.Value,digitsPattern));
            maxSess = fIRST + app.SessionNUM.Value - 1;
            SessionNUMs = num2cell(transpose(fIRST:maxSess));
            SessionIDs = repmat({'Choose'},numel(SessionNUMs),1);
            startINDS = num2cell(ones(size(SessionNUMs)));
            stopINDS = num2cell(ones(size(SessionNUMs)));


            % Calculate max difference in time
            tmpTime = convertUNIXtime(app,app.curSESSold.SessionInfo.ts);
            [~,indices] = sort(diff(tmpTime),'descend');
            borderS = round(numel(tmpTime)*0.01);
            exIndices = indices(indices > borderS & indices < numel(tmpTime) - borderS);

            % Index 2 = 2-3 : Max Index is the end of the prior session
            % Get the number of sessions and sort - ascending
            maxINDEX = num2cell(transpose(sort(exIndices(1:numel(SessionNUMs)),'ascend')));
  
            myData = [SessionNUMs,SessionIDs,startINDS,stopINDS,maxINDEX];

            app.SessChange.ColumnFormat = ({[] {'Behavior' 'Resting State'} [] [] []});
            app.SessChange.Data = myData;
            app.SessChange.ColumnEditable = [false,true,true,true,false];
            
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.defaultDet = 0;
            app.saveSessCount = 1;
            app.SaveSession = cell(100,1);
        end

        % Value changed function: SessList
        function SessListValueChanged(app, event)

            curSessiON = app.SessList.Value;
            % Check which Event file the user clicked on
            if strcmp(curSessiON,'...')
                % If the first value than return
                varNames = {'Session#','SessionType','StartIndex','DurMins','NumEvents'};
                app.SessTable.Data = table("'" , "'" , "'" , "'" , "'",...
                    'VariableNames',varNames);

                app.ProcSess.Enable = 'off';
                return
            end

            sessCLICKED = str2double(extract(curSessiON,digitsPattern));
            app.curSESSold = app.sessLOGdat{sessCLICKED};

            numTTLs = numel(app.curSESSold.SessionInfo.ttl);
            indexSTART = app.curSESSold.StartIndex;
%             indexEND = app.curSESSold.StopIndex;
            duration = minutes(seconds(app.curSESSold.StopTime/1000000 -  app.curSESSold.StartTime/1000000));
            sessTYPE = app.curSESSold.SessType;

            app.EventDat.ts = app.curSESSold.SessionInfo.ts;
            app.EventDat.ttl = app.curSESSold.SessionInfo.ttl;
            app.EventDat.header = app.curSESSold.SessionInfo.header;
            app.EventDat.tInfo = app.curSESSold.SessionInfo.tInfo;

            app.SessionNEW.Enable = 'on';

            % Populate table
            varNames = {'Session#','SessionType','StartIndex','DurMins','NumEvents'};
            app.SessTable.Data = table(string(curSessiON) , string(sessTYPE) , indexSTART , duration , numTTLs,...
                'VariableNames',varNames);

            % Activate Plot button

            app.SessionNEW.Enable = 'on';

            app.ProcSess.Enable = 'on';

        end

        % Callback function
        function Back2topMenuSelected(app, event)
            scroll(app.statusMess, 'top')
        end

        % Callback function
        function ClearMenuSelected(app, event)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Callback function
        function SessCreateButtonPushed(app, event)


            if strcmp(app.ACCEPTSwitch.Value,'On')
                % Loop through sessions
                sessNUM = app.SessionNumberSpinner.Value;

                sessSel = app.SessionTypeButtonGroup.SelectedObject.Text;

                tmpSession = struct;
                for si = 1:sessNUM
                    tmpStartI = app.StartIndexSpinner.Value;
                    tmpStopI = app.StopIndexSpinner.Value;
                    % Populate defaults

                    % Extract data
                    tmpSession.StartIndex(si) = tmpStartI;
                    tmpSession.StopIndex(si)  = tmpStopI;
                    tmpSession.StartTime(si)  = app.EventDat.ts(tmpStartI) - 2*1000000;
                    tmpSession.StopTime(si)   = app.EventDat.ts(tmpStopI) + 2*1000000;
                    tmpSession.StartStr{si}   = app.EventDat.tInfo{tmpStartI};
                    tmpSession.StopStr{si}    = app.EventDat.tInfo{tmpStopI};

                end

                % All other information
                tmpSession.SessionInfo = app.EventDat;
                tmpSession.SessType = sessSel;
                tmpSession.DateRec = convertUNIXtime(app, app.EventDat.ts(1));

                app.SaveSession{app.saveSessCount} = tmpSession;
                app.saveSessCount = app.saveSessCount + 1;

                % Update Table
                curEvent = app.SessList.Value;
                eventIND = contains(app.eventLogtab.EventFnames, curEvent);
                tmpDate1 = convertUNIXtime(app,app.timestamps(1));
                app.eventLogtab.DateRecord(eventIND) = tmpDate1;
                app.eventLogtab.ViewFile(eventIND) = false;
                app.eventLogtab.ReasonNotV{eventIND} = sessSel;

                % Save table
                cd(app.eventLoc)
                eventTtable = app.eventLogtab;
                save("EventLogTracker.mat","eventTtable")

                % Remove from Event List
                cureventLIST = app.SessList.Items;
                app.SessList.Items =  cureventLIST(~contains(cureventLIST,curEvent));

                app.ProcSess.Enable = 'on';

                % Re-enable Event List and Session Info and Clear Session
                % Info
                app.SessList.Enable = 'on';
                app.SessionNEW.Enable = 'on';

                app.SessionNumberSpinner.Value = 1;
                app.StartIndexSpinner.Value = 1;
                app.StopIndexSpinner.Value = 1;
                app.ACCEPTSwitch.Value = 'Off';

                % Clear table values
                app.SessTable.Data = [];
                app.ACCEPTSwitch.Enable = 'off';

            elseif strcmp(app.ACCEPTSwitch.Value,'Off')
                return
            end


        end

        % Button pushed function: closeProg
        function closeProgButtonPushed(app, event)
            closeFunction(app)
        end

        % Button pushed function: ProcSess
        function ProcSessButtonPushed(app, event)

            % Activate SessionSplit Panel
            app.SessionSplit.Enable = 'on';
            app.SessList.Enable = 'off';
            app.ProcSess.Enable = 'off';

%             % Subject ID
%             prompt = 'Subject ID';
%             dlgtitle = 'SUBJECT';
%             dims = [1 35];
%             definput = {'SUBJECT_ID'};
%             subID = inputdlg(prompt,dlgtitle,dims,definput);
%
              % Update Modular tables
              % Calculate from current and add
              % First = Current Session ID #
              % Next = Max Number of Sessions
              updateSESSchange(app)


        end

        % Button pushed function: foldCREATE
        function foldCREATEButtonPushed(app, event)

            % User Locates a subject folder .mat setFold file
            % UICONFIRM PROMPT TO SELECT RAW FOLDER
            userSel = uiconfirm(app.NLXEventFinderUIFigure, 'SELECT nwbCKfold.mat file',...
                "FOLDER Location file", "Icon","info");

            if matches(userSel,"OK")

                [app.locsFILE , app.mainLoc] = uigetfile('*.mat');

                if isempty(app.locsFILE) || ~matches('nwbCKfold.mat' , app.locsFILE)
                    uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                    return
                else

                    cd(app.mainLoc)

                    load(app.locsFILE , 'nwbFilesLOCS')

                    app.sessLOC = nwbFilesLOCS.sessSAVloc;
                    app.eventLoc = nwbFilesLOCS.eventLOC;

                    % CD to session Folder and load session Files
                    cd(app.sessLOC)

                    matfindck = dir('*.mat');
                    matFindckN = {matfindck.name};

                    if isempty(matFindckN) || ~matches('allSessionData.mat',matFindckN)
                        uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                        return
                    else
                        app.sessFILE = matFindckN{1};
                    end

                    load(app.sessFILE, "sessionINFo" , "saveSessAll")

                    % Save table
                    cd(app.eventLoc)
                    
                    load("EventLogTracker.mat","eventTtable")
                    app.eventLogtab = eventTtable; 

                    app.sessLOGdat = saveSessAll;
                    app.sessINFO = sessionINFo;

                    app.foldCREATE.Enable = 'off';


                end

            else

                return

            end

            % Populate SESSION LIST
            numSESS = num2cell(1:length(app.sessLOGdat));
            sessLISTv = cellfun(@(x) ['Session',num2str(x)], numSESS, "UniformOutput",false);

            app.SessList.Items = ['...',sessLISTv];

            app.SessList.Enable = 'on';


        end

        % Value changed function: ACCEPTButton
        function ACCEPTButtonValueChanged(app, event)
            value = app.ACCEPTButton.Value;

            if ~value
                app.ACCEPTButton.Text = 'SET';
                app.SessionNUM.Enable = 'on';
                app.SPLIT.Enable = 'off';
                app.SessChange.Enable = 'on';
            else
                app.ACCEPTButton.Text = 'RESET';
                app.SessionNUM.Enable = 'off';
                app.SPLIT.Enable = 'on';
                app.SessChange.Enable = 'off';
            end
            
        end

        % Button pushed function: SPLIT
        function SPLITButtonPushed(app, event)
            
            
            app.SessList.Enable = 'on';
            app.SessionSplit.Enable = 'off';


        end

        % Value changed function: SessionNUM
        function SessionNUMValueChanged(app, event)
            updateSESSchange(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLXEventFinderUIFigure = uifigure('Visible', 'off');
            app.NLXEventFinderUIFigure.Name = 'NLX Event Finder';
            app.NLXEventFinderUIFigure.Icon = 'icon.png';
            app.SessionListLabel = uilabel(app.NLXEventFinderUIFigure);
            app.SessionListLabel.Text = 'Session List';
            app.SessList = uilistbox(app.NLXEventFinderUIFigure);
            app.SessList.Items = {'...'};
            app.SessList.ValueChangedFcn = createCallbackFcn(app, @SessListValueChanged, true);
            app.SessList.Enable = 'off';
            app.SessList.Value = '...';
            app.SessTable = uitable(app.NLXEventFinderUIFigure);
            app.SessTable.ColumnName = {'Session#'; 'Records'; 'Time Start'; 'Duration'; 'Number of TTLs'};
            app.SessTable.RowName = {};
            app.SessionNEW = uipanel(app.NLXEventFinderUIFigure);
            app.SessionNEW.Enable = 'off';
            app.SessionNEW.Title = 'New Session';
            app.MENU = uipanel(app.NLXEventFinderUIFigure);
            app.ProcSess = uibutton(app.MENU, 'push');
            app.ProcSess.ButtonPushedFcn = createCallbackFcn(app, @ProcSessButtonPushed, true);
            app.ProcSess.Icon = 'processButton.png';
            app.ProcSess.Enable = 'off';
            app.ProcSess.Text = '';
            app.closeProg = uibutton(app.MENU, 'push');
            app.closeProg.ButtonPushedFcn = createCallbackFcn(app, @closeProgButtonPushed, true);
            app.closeProg.Icon = 'closeButton.png';
            app.closeProg.Text = '';
            app.foldCREATE = uibutton(app.MENU, 'push');
            app.foldCREATE.ButtonPushedFcn = createCallbackFcn(app, @foldCREATEButtonPushed, true);
            app.foldCREATE.Icon = 'FolderCREATE.png';
            app.foldCREATE.Text = '';
            app.ExpSess = uibutton(app.MENU, 'push');
            app.ExpSess.Icon = 'exportButton.png';
            app.ExpSess.Enable = 'off';
            app.ExpSess.Text = '';
            app.SessionSplit = uipanel(app.NLXEventFinderUIFigure);
            app.SessionSplit.Enable = 'off';
            app.SessionSplit.Title = 'Session Split';
            app.NumberSessionsSpinnerLabel = uilabel(app.SessionSplit);
            app.NumberSessionsSpinnerLabel.Text = {'Number'; 'Sessions'};
            app.SessionNUM = uispinner(app.SessionSplit);
            app.SessionNUM.Limits = [1 20];
            app.SessionNUM.ValueChangedFcn = createCallbackFcn(app, @SessionNUMValueChanged, true);
            app.SessionNUM.Value = 1;
            app.ACCEPTButton = uibutton(app.SessionSplit, 'state');
            app.ACCEPTButton.ValueChangedFcn = createCallbackFcn(app, @ACCEPTButtonValueChanged, true);
            app.ACCEPTButton.Text = 'SET';
            app.SessChange = uitable(app.SessionSplit);
            app.SessChange.ColumnName = {'Session#'; 'SessionID'; 'StartIndex'; 'StopIndex'; 'OffsetIndex'};
            app.SessChange.RowName = {};
            app.SessChange.ColumnEditable = [false true true true false];
            app.SPLIT = uibutton(app.SessionSplit, 'push');
            app.SPLIT.ButtonPushedFcn = createCallbackFcn(app, @SPLITButtonPushed, true);
            app.SPLIT.Enable = 'off';
            app.SPLIT.Text = 'SPLIT';
            % Show the figure after all components are created
            app.NLXEventFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_SessionFix_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLXEventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLXEventFinderUIFigure)
        end
    end
end