classdef NLX_SessionFix_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLXEventFinderUIFigure      matlab.ui.Figure
        SessionSplit                matlab.ui.container.Panel
        SPLIT                       matlab.ui.control.Button
        SessChange                  matlab.ui.control.Table
        ACCEPTButton                matlab.ui.control.StateButton
        SessionNUM                  matlab.ui.control.Spinner
        NumberSessionsSpinnerLabel  matlab.ui.control.Label
        MENU                        matlab.ui.container.Panel
        DONEButton                  matlab.ui.control.StateButton
        ExpSess                     matlab.ui.control.Button
        foldCREATE                  matlab.ui.control.Button
        closeProg                   matlab.ui.control.Button
        ProcSess                    matlab.ui.control.Button
        SessionNEW                  matlab.ui.container.Panel
        newSESS                     matlab.ui.control.Table
        SessTable                   matlab.ui.control.Table
        SessList                    matlab.ui.control.ListBox
        SessionListLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        eventLoc % Location for event files
        nevLIST
        timestamps
        ttl
        header
        evText
        EventDat
        maxRec
        defaultDet
        SaveSession
        saveSessCount
        sessSaveLoc
        eventLogtab
        LogTasks
        useEvList

        % Added from update
        locsFILE
        mainLoc
        sessLoc
        nwbLoc
        sessLOC
        nwbLOC
        sessFILE
        sessLOGdat
        sessINFO

        curSESSold
        curSESSnew

        allSESSold
        allSESSnew

    end

    %%%% TO DO
    %% 8/10/2021
    % 3. Create a DONE button - Toggle
    %    a. When depressed turns everything off - opens Export
    %    b. When not depressed opens Session List
    % 4. Use Export Sessions to:
    %    a. SAVE OUT new allsession.mat
    %    b. SAVE OUT old allsession.mat


    methods (Access = private)

        function closeFunction(app)
            selection = uiconfirm(app.NLXEventFinderUIFigure,'Close PROGRAM?',...
                'Confirmation');

            switch selection
                case 'OK'
                    delete(app.NLXEventFinderUIFigure)

                case 'Cancel'
                    return
            end

        end

        function matdateStr = convertUNIXtime(~, uniXTimes) % '~' is placeholder for app

            matdateStr = NaT(size(uniXTimes),'TimeZone','America/Denver');
            for ci = 1:length(uniXTimes)
                timeREcord = datetime(uniXTimes(ci)/1000000,'ConvertFrom',...
                    'posixtime','TimeZone','America/Denver');
                matdateStr(ci) = timeREcord;
            end

        end

        function createLOGtable(app)
            EventFnames = transpose(app.nevLIST);
            ViewFile = ones(size(EventFnames),'logical');
            ReasonNotV = repmat({''},length(EventFnames),1);
            LOGviewed = zeros(size(EventFnames),'logical');
            NeuroEvents = repmat({''},length(EventFnames),1);
            DateRecord = NaT(size(EventFnames),'TimeZone','America/Denver');
            StartTime = NaT(size(EventFnames),'TimeZone','America/Denver');
            app.eventLogtab = table(EventFnames,ViewFile,ReasonNotV,LOGviewed,NeuroEvents,DateRecord,StartTime);
        end

        function updateSESSchange(app)

            fIRST = str2double(extract(app.SessList.Value,digitsPattern));
            maxSess = fIRST + app.SessionNUM.Value - 1;
            SessionNUMs = num2cell(transpose(fIRST:maxSess));
            SessionIDs = repmat({'Choose'},numel(SessionNUMs),1);
            startINDS = num2cell(ones(size(SessionNUMs)));
            stopINDS = num2cell(ones(size(SessionNUMs)));


            % Calculate max difference in time
            tmpTime = convertUNIXtime(app,app.curSESSold.SessionInfo.ts);
            [~,indices] = sort(diff(tmpTime),'descend');
            borderS = round(numel(tmpTime)*0.01);
            exIndices = indices(indices > borderS & indices < numel(tmpTime) - borderS);

            % Index 2 = 2-3 : Max Index is the end of the prior session
            % Get the number of sessions and sort - ascending
            maxINDEX = [num2cell(transpose(sort(exIndices(1:numel(SessionNUMs)-1),'ascend')));{NaN}];

            myData = [SessionNUMs,SessionIDs,startINDS,stopINDS,maxINDEX];

            app.SessChange.ColumnFormat = ({[] {'Behavior' 'Resting State'} [] [] []});
            app.SessChange.Data = myData;
            app.SessChange.ColumnEditable = [false,true,true,true,false];

        end


        function updateNEWsessTable(app , stage)
            
            switch stage
                case 1

                    varNames = {'Session#','SessionID','StartIndex','Duration','#TTLs'};
                    app.newSESS.Data = table("" , "" , "" , "" , "",...
                        'VariableNames',varNames);

                case 2

                    sessCLICKED = str2double(extract(app.SessList.Value,digitsPattern));
                    app.curSESSold = app.sessLOGdat{sessCLICKED};

                    % Split into multi structs by app.SessionNUM

                    sessIDS = app.SessChange.Data(:,1);
                    sessIONID = cellfun(@(x) ['Session' , num2str(x)], sessIDS,'UniformOutput',false);
                    reCORDS = repmat({app.curSESSold.SessType},app.SessionNUM.Value,1);
                    startINDX = app.SessChange.Data(:,3);
                    stopINDX = app.SessChange.Data(:,4);
                    tmpDUR = cell(size(sessIDS));
                    numTTLs = cell(size(sessIDS));


                    for si = 1:app.SessionNUM.Value

                        stopTIME = app.curSESSold.SessionInfo.ts(stopINDX{si});
                        startTIME = app.curSESSold.SessionInfo.ts(startINDX{si});

                        tmpDUR{si} = minutes(seconds(stopTIME/1000000 -...
                            startTIME/1000000));
                        numTTLs{si} = numel(startINDX{si}:stopINDX{si});

                    end

                    % Populate table
                    varNames = {'Session#','SessionID','StartIndex','Duration','#TTLs'};
                    app.newSESS.Data = table(sessIONID , reCORDS , startINDX , tmpDUR , numTTLs,...
                        'VariableNames',varNames);

                    % Stored in allSESSnew cell - index with allSESSold logical

                case 3

                    % UPDATE TABLE FOR VIEWING
                    sessCLICKED = str2double(extract(app.SessList.Value,digitsPattern));
                    tmpNEWSess = app.allSESSnew{sessCLICKED};

                    sessIONID = cell(length(tmpNEWSess),1);
                    reCORDS = cell(length(tmpNEWSess),1);
                    startINDX = cell(length(tmpNEWSess),1);
                    tmpDUR = cell(length(tmpNEWSess),1);
                    numTTLs = cell(length(tmpNEWSess),1);

                    for ti = 1:length(tmpNEWSess)
                        sessIONID{ti} = ['Session' , num2str(sessCLICKED + ti - 1)];
                        reCORDS{ti} = tmpNEWSess{ti}.SessType;
                        startINDX{ti} = tmpNEWSess{ti}.StartIndex;
                        tmpDUR{ti} = minutes(seconds(tmpNEWSess{ti}.StopTime/1000000 -...
                            tmpNEWSess{ti}.StartTime/1000000));
                        numTTLs{ti} = numel(startINDX{ti}:tmpNEWSess{ti}.StopIndex);
                    end

                    % Populate table
                    varNames = {'Session#','SessionID','StartIndex','Duration','#TTLs'};
                    app.newSESS.Data = table(sessIONID , reCORDS , startINDX , tmpDUR , numTTLs,...
                        'VariableNames',varNames);

            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.defaultDet = 0;
            app.saveSessCount = 1;
            app.SaveSession = cell(100,1);
            
            currentLOC = pwd;
            resourceLOC = [currentLOC, filesep, 'NLX_IO_Code'];
            addpath(genpath(resourceLOC))
            
            
        end

        % Value changed function: SessList
        function SessListValueChanged(app, event)

            varNames = {'Session#','SessionID','StartIndex','StopIndex','OffsetIndex'};
            app.SessChange.Data = table("" , "" , "" , "" , "",...
                'VariableNames',varNames);

            % Clear original Session Table
            varNames = {'Session#','SessionID','StartIndex','Duration','#TTLs'};
            app.SessTable.Data = table("'" , "'" , "'" , "'" , "'",...
                'VariableNames',varNames);

            % Clear new session table
            updateNEWsessTable(app , 1)


            curSessiON = app.SessList.Value;
            % Check which Event file the user clicked on
            if strcmp(curSessiON,'...')
                % If the first value than return
                varNames = {'Session#','SessionID','StartIndex','Duration','#TTLs'};
                app.SessTable.Data = table("'" , "'" , "'" , "'" , "'",...
                    'VariableNames',varNames);

                app.ProcSess.Enable = 'off';
                return
            end

            sessCLICKED = str2double(extract(curSessiON,digitsPattern));

            % CHECK IF ALREADY PROCESSED
            app.curSESSold = app.sessLOGdat{sessCLICKED};

            numTTLs = numel(app.curSESSold.SessionInfo.ttl);
            indexSTART = app.curSESSold.StartIndex;
            %             indexEND = app.curSESSold.StopIndex;
            duration = minutes(seconds(app.curSESSold.StopTime/1000000 -  app.curSESSold.StartTime/1000000));
            sessTYPE = app.curSESSold.SessType;

            app.EventDat.ts = app.curSESSold.SessionInfo.ts;
            app.EventDat.ttl = app.curSESSold.SessionInfo.ttl;
            app.EventDat.header = app.curSESSold.SessionInfo.header;
            app.EventDat.tInfo = app.curSESSold.SessionInfo.tInfo;

            % Populate table
            varNames = {'Session#','SessionID','StartIndex','Duration','#TTLs'};
            app.SessTable.Data = table(string(curSessiON) , string(sessTYPE) , indexSTART , duration , numTTLs,...
                'VariableNames',varNames);

            % Activate Plot button
            if app.allSESSold(sessCLICKED)

                app.ProcSess.Enable = 'off';
                updateNEWsessTable(app , 3)

            else

                app.ProcSess.Enable = 'on';

            end

        end

        % Callback function
        function Back2topMenuSelected(app, event)
            scroll(app.statusMess, 'top')
        end

        % Callback function
        function ClearMenuSelected(app, event)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Callback function
        function SessCreateButtonPushed(app, event)

            tmpSession = struct;
            for si = 1:sessNUM
                tmpStartI = app.StartIndexSpinner.Value;
                tmpStopI = app.StopIndexSpinner.Value;
                % Populate defaults

                % Extract data
                tmpSession.StartIndex(si) = tmpStartI;
                tmpSession.StopIndex(si)  = tmpStopI;
                tmpSession.StartTime(si)  = app.EventDat.ts(tmpStartI) - 2*1000000;
                tmpSession.StopTime(si)   = app.EventDat.ts(tmpStopI) + 2*1000000;
                tmpSession.StartStr{si}   = app.EventDat.tInfo{tmpStartI};
                tmpSession.StopStr{si}    = app.EventDat.tInfo{tmpStopI};

            end

            % All other information
            tmpSession.SessionInfo = app.EventDat;
            tmpSession.SessType = sessSel;
            tmpSession.DateRec = convertUNIXtime(app, app.EventDat.ts(1));

            app.SaveSession{app.saveSessCount} = tmpSession;
            app.saveSessCount = app.saveSessCount + 1;

            % Update Table
            curEvent = app.SessList.Value;
            eventIND = contains(app.eventLogtab.EventFnames, curEvent);
            tmpDate1 = convertUNIXtime(app,app.timestamps(1));
            app.eventLogtab.DateRecord(eventIND) = tmpDate1;
            app.eventLogtab.ViewFile(eventIND) = false;
            app.eventLogtab.ReasonNotV{eventIND} = sessSel;

            % Save table
            cd(app.eventLoc)
            eventTtable = app.eventLogtab;
            save("EventLogTracker.mat","eventTtable")

            % Remove from Event List
            cureventLIST = app.SessList.Items;
            app.SessList.Items =  cureventLIST(~contains(cureventLIST,curEvent));

            app.ProcSess.Enable = 'on';

            % Re-enable Event List and Session Info and Clear Session
            % Info
            app.SessList.Enable = 'on';
            app.SessionNEW.Enable = 'on';

            app.SessionNumberSpinner.Value = 1;
            app.StartIndexSpinner.Value = 1;
            app.StopIndexSpinner.Value = 1;
            app.ACCEPTSwitch.Value = 'Off';

            % Clear table values
            app.SessTable.Data = [];
            app.ACCEPTSwitch.Enable = 'off';


        end

        % Button pushed function: closeProg
        function closeProgButtonPushed(app, event)
            closeFunction(app)
        end

        % Button pushed function: ProcSess
        function ProcSessButtonPushed(app, event)

            % Activate SessionSplit Panel
            app.SessionSplit.Enable = 'on';
            app.SessList.Enable = 'off';
            app.ProcSess.Enable = 'off';

            %             % Subject ID
            %             prompt = 'Subject ID';
            %             dlgtitle = 'SUBJECT';
            %             dims = [1 35];
            %             definput = {'SUBJECT_ID'};
            %             subID = inputdlg(prompt,dlgtitle,dims,definput);
            %
            % Update Modular tables
            % Calculate from current and add
            % First = Current Session ID #
            % Next = Max Number of Sessions
            updateSESSchange(app)


        end

        % Button pushed function: foldCREATE
        function foldCREATEButtonPushed(app, event)

            % User Locates a subject folder .mat setFold file
            % UICONFIRM PROMPT TO SELECT RAW FOLDER
            userSel = uiconfirm(app.NLXEventFinderUIFigure, 'SELECT nwbCKfold.mat file',...
                "FOLDER Location file", "Icon","info");

            if matches(userSel,"OK")

                [app.locsFILE , app.mainLoc] = uigetfile('*.mat');

                if isempty(app.locsFILE) || ~matches('nwbCKfold.mat' , app.locsFILE)
                    uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                    return
                else

                    cd(app.mainLoc)

                    load(app.locsFILE , 'nwbFilesLOCS')

                    app.sessLOC = nwbFilesLOCS.sessSAVloc;
                    app.eventLoc = nwbFilesLOCS.eventLOC;

                    % CD to session Folder and load session Files
                    cd(app.sessLOC)

                    matfindck = dir('*.mat');
                    matFindckN = {matfindck.name};

                    if isempty(matFindckN) || ~matches('allSessionData.mat',matFindckN)
                        uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                        return
                    else
                        app.sessFILE = matFindckN{1};
                    end

                    load(app.sessFILE, "sessionINFo" , "saveSessAll")

                    % Save table
                    cd(app.eventLoc)

                    load("EventLogTracker.mat","eventTtable")
                    app.eventLogtab = eventTtable;

                    app.sessLOGdat = saveSessAll;
                    app.sessINFO = sessionINFo;

                    app.foldCREATE.Enable = 'off';

                    app.allSESSold = false(length(app.sessLOGdat),1);
                    app.allSESSnew = cell(length(app.sessLOGdat),1);


                end

            else

                return

            end

            % Populate SESSION LIST
            numSESS = num2cell(1:length(app.sessLOGdat));
            sessLISTv = cellfun(@(x) ['Session',num2str(x)], numSESS, "UniformOutput",false);

            app.SessList.Items = ['...',sessLISTv];

            app.SessList.Enable = 'on';
            app.DONEButton.Enable = 'on';


        end

        % Value changed function: ACCEPTButton
        function ACCEPTButtonValueChanged(app, event)
            value = app.ACCEPTButton.Value;

            if ~value
                app.ACCEPTButton.Text = 'SET';
                app.SessionNUM.Enable = 'on';
                app.SPLIT.Enable = 'off';
                app.SessionNEW.Enable = 'off';

                % Keep New Session Table empty [update function]
                updateNEWsessTable(app , 1)

            else
                app.ACCEPTButton.Text = 'RESET';
                app.SessionNUM.Enable = 'off';
                app.SPLIT.Enable = 'on';
                app.SessionNEW.Enable = 'on';

                % Update New Session Table with new data
                % Use update function
                updateNEWsessTable(app , 2)

            end

        end

        % Button pushed function: SPLIT
        function SPLITButtonPushed(app, event)

            app.SessList.Enable = 'on';
            app.SessionSplit.Enable = 'off';

            % ADD NEW DATA to allSESSnew cell
            % ADD TRUE to allSESSold logical

            % Clear Session OLD table
            varNames = {'Session#','SessionID','StartIndex','Duration','#TTLs'};
            app.SessTable.Data = table("'" , "'" , "'" , "'" , "'",...
                'VariableNames',varNames);

            % Save allSESSold logical
            oldSESSind = str2double(extract(app.SessList.Value,digitsPattern));
            app.allSESSold(oldSESSind) = true;

            % Save allSESSnew cell
            startINDX = app.SessChange.Data(:,3);
            stopINDX = app.SessChange.Data(:,4);

            for si = 1:app.SessionNUM.Value
                
                tmpCon = struct;

                tmpCon.StartIndex = startINDX{si};
                tmpCon.StopIndex = stopINDX{si};
                tmpCon.StartTime = app.curSESSold.SessionInfo.ts(startINDX{si});
                tmpCon.StopTime = app.curSESSold.SessionInfo.ts(stopINDX{si});
                tmpCon.StartStr = app.curSESSold.SessionInfo.tInfo(startINDX{si});
                tmpCon.StopStr = app.curSESSold.SessionInfo.tInfo(stopINDX{si});
                tmpCon.SessType = app.curSESSold.SessType;
                tmpCon.DateRec = datetime(datestr(now));

                % If it is the first do x
                if si == 1
                    startIII = 1;
                else
                    startIII = startINDX{si};
                end
                stopIII = stopINDX{si};

                tmpCon.SessionInfo.ts = app.curSESSold.SessionInfo.ts(startIII:stopIII);
                tmpCon.SessionInfo.ttl = app.curSESSold.SessionInfo.ttl(startIII:stopIII);
                tmpCon.SessionInfo.tInfo = app.curSESSold.SessionInfo.tInfo(startIII:stopIII);

                tmpCon.SessionInfo.header = app.curSESSold.SessionInfo.header;
                app.allSESSnew{oldSESSind}{si} = tmpCon;

            end

            % Reset list
            app.SessList.Value = '...';

            % Clear Session New Table
            updateNEWsessTable(app , 1)

            % Clear SessChange
            varNames = {'Session#','SessionID','StartIndex','StopIndex','OffsetIndex'};
            app.SessChange.Data = table("" , "" , "" , "" , "",...
                'VariableNames',varNames);

            % Reset - reset button
            app.ACCEPTButton.Value = 0;
            app.ACCEPTButton.Text = 'SET';
            app.SessionNUM.Enable = 'on';
            app.SPLIT.Enable = 'off';
            app.SessionNEW.Enable = 'off';

        end

        % Value changed function: SessionNUM
        function SessionNUMValueChanged(app, event)
            updateSESSchange(app)

        end

        % Value changed function: DONEButton
        function DONEButtonValueChanged(app, event)
            value = app.DONEButton.Value;

            if value
                % close all and activate Export
                % Clear Session Change Table
                varNames = {'Session#','SessionID','StartIndex','StopIndex','OffsetIndex'};
                app.SessChange.Data = table("" , "" , "" , "" , "",...
                    'VariableNames',varNames);

                % Clear original Session Table
                varNames = {'Session#','SessionID','StartIndex','Duration','#TTLs'};
                app.SessTable.Data = table("'" , "'" , "'" , "'" , "'",...
                    'VariableNames',varNames);

                % Clear new session table
                updateNEWsessTable(app , 1)

                % Deactivate all panels
                app.SessionSplit.Enable = 'off';
                app.SessionNEW.Enable = 'off';
                % Deactivate Session List
                app.SessList.Enable = 'off';
                % Deactivate Session Process
                app.ProcSess.Enable = 'off';
                % Open Export
                app.ExpSess.Enable = 'on';

            else 

                % Open Export
                app.ExpSess.Enable = 'off';
                % Deactivate Session List
                app.SessList.Enable = 'on';

            end
            
        end

        % Button pushed function: ExpSess
        function ExpSessButtonPushed(app, event)
            % Compile new Session.mat with combined new and old

            % Create INPROGRESS WINDOW

            oldData = app.sessLOGdat;
            newData = app.allSESSnew;
            sessLOCt = app.sessLOC;
            sessFname = app.sessFILE;

            % Overwrite old sessionall
            modData = cell(length(newData)*3,1);
            curLOC = 1;
            for ni = 1:length(newData)

                tmpDat = newData{ni};

                if isempty(tmpDat)
                    modData{curLOC} = oldData{ni};
                    curLOC = curLOC + 1;
                else
                    for tmpNw = 1:length(tmpDat)
                        modData{curLOC} = tmpDat{tmpNw};
                        curLOC = curLOC + 1;
                    end
                end

            end

            modDataO = modData(cellfun(@(x) ~isempty(x), modData, 'UniformOutput',true));

            % Create a new sessionall with old version
            saveSessAllold = oldData;
            saveSessAll = modDataO;

            cd(sessLOCt)
            save(sessFname, 'saveSessAll','saveSessAllold','-append');

            % Close program
            closeFunction(app)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLXEventFinderUIFigure = uifigure('Visible', 'off');
            app.NLXEventFinderUIFigure.Name = 'NLX Event Finder';
            app.NLXEventFinderUIFigure.Icon = 'icon.png';
            app.SessionListLabel = uilabel(app.NLXEventFinderUIFigure);
            app.SessionListLabel.Text = 'Session List';
            app.SessList = uilistbox(app.NLXEventFinderUIFigure);
            app.SessList.Items = {'...'};
            app.SessList.ValueChangedFcn = createCallbackFcn(app, @SessListValueChanged, true);
            app.SessList.Enable = 'off';
            app.SessList.Value = '...';
            app.SessTable = uitable(app.NLXEventFinderUIFigure);
            app.SessTable.ColumnName = {'Session#'; 'SessionID'; 'StartIndex'; 'Duration'; '#TTLs'};
            app.SessTable.RowName = {};
            app.SessionNEW = uipanel(app.NLXEventFinderUIFigure);
            app.SessionNEW.Enable = 'off';
            app.SessionNEW.Title = 'New Session';
            app.newSESS = uitable(app.SessionNEW);
            app.newSESS.ColumnName = {'Session#'; 'SessionID'; 'StartIndex'; 'Duration'; '#TTLs'};
            app.newSESS.RowName = {};
            app.MENU = uipanel(app.NLXEventFinderUIFigure);
            app.ProcSess = uibutton(app.MENU, 'push');
            app.ProcSess.ButtonPushedFcn = createCallbackFcn(app, @ProcSessButtonPushed, true);
            app.ProcSess.Icon = 'processButton.png';
            app.ProcSess.Enable = 'off';
            app.ProcSess.Text = '';
            app.closeProg = uibutton(app.MENU, 'push');
            app.closeProg.ButtonPushedFcn = createCallbackFcn(app, @closeProgButtonPushed, true);
            app.closeProg.Icon = 'closeButton.png';
            app.closeProg.Text = '';
            app.foldCREATE = uibutton(app.MENU, 'push');
            app.foldCREATE.ButtonPushedFcn = createCallbackFcn(app, @foldCREATEButtonPushed, true);
            app.foldCREATE.Icon = 'FolderCREATE.png';
            app.foldCREATE.Text = '';
            app.ExpSess = uibutton(app.MENU, 'push');
            app.ExpSess.ButtonPushedFcn = createCallbackFcn(app, @ExpSessButtonPushed, true);
            app.ExpSess.Icon = 'exportButton.png';
            app.ExpSess.Enable = 'off';
            app.ExpSess.Text = '';
            app.DONEButton = uibutton(app.MENU, 'state');
            app.DONEButton.ValueChangedFcn = createCallbackFcn(app, @DONEButtonValueChanged, true);
            app.DONEButton.Enable = 'off';
            app.DONEButton.Text = 'DONE!';
            app.SessionSplit = uipanel(app.NLXEventFinderUIFigure);
            app.SessionSplit.Enable = 'off';
            app.SessionSplit.Title = 'Session Split';
            app.NumberSessionsSpinnerLabel = uilabel(app.SessionSplit);
            app.NumberSessionsSpinnerLabel.Text = {'Number'; 'Sessions'};
            app.SessionNUM = uispinner(app.SessionSplit);
            app.SessionNUM.Limits = [2 20];
            app.SessionNUM.ValueChangedFcn = createCallbackFcn(app, @SessionNUMValueChanged, true);
            app.SessionNUM.Value = 2;
            app.ACCEPTButton = uibutton(app.SessionSplit, 'state');
            app.ACCEPTButton.ValueChangedFcn = createCallbackFcn(app, @ACCEPTButtonValueChanged, true);
            app.ACCEPTButton.Text = 'SET';
            app.SessChange = uitable(app.SessionSplit);
            app.SessChange.ColumnName = {'Session#'; 'SessionID'; 'StartIndex'; 'StopIndex'; 'OffsetIndex'};
            app.SessChange.RowName = {};
            app.SessChange.ColumnEditable = [false true true true false];
            app.SPLIT = uibutton(app.SessionSplit, 'push');
            app.SPLIT.ButtonPushedFcn = createCallbackFcn(app, @SPLITButtonPushed, true);
            app.SPLIT.Enable = 'off';
            app.SPLIT.Text = 'SPLIT';
            % Show the figure after all components are created
            app.NLXEventFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_SessionFix_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLXEventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLXEventFinderUIFigure)
        end
    end
end