classdef NLX_Session2Neuro_v10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        confirmINFO                matlab.ui.control.Switch
        CONFIRMINFOSwitchLabel     matlab.ui.control.Label
        SubjectInfoP               matlab.ui.container.Panel
        SubSexDropDown             matlab.ui.control.DropDown
        SubSexDropDownLabel        matlab.ui.control.Label
        SubAgeEditField            matlab.ui.control.NumericEditField
        SubAgeEditFieldLabel       matlab.ui.control.Label
        SessionInfoP               matlab.ui.container.Panel
        ExperimenterDropDown       matlab.ui.control.DropDown
        ExperimenterDropDownLabel  matlab.ui.control.Label
        SessionIDDropDown          matlab.ui.control.DropDown
        SessionIDDropDownLabel     matlab.ui.control.Label
        SPpanel                    matlab.ui.container.Panel
        step1                      matlab.ui.control.Label
        chkNWB                     matlab.ui.control.Button
        expNWB                     matlab.ui.control.Button
        importSEEG                 matlab.ui.control.Button
        step3                      matlab.ui.control.Label
        step2                      matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        DLpanel                    matlab.ui.container.Panel
        START                      matlab.ui.control.Button
        resetNWB                   matlab.ui.control.Button
        nwbLoc                     matlab.ui.control.EditField
        NWBLabel                   matlab.ui.control.Label
        resetSE                    matlab.ui.control.Button
        resetEV                    matlab.ui.control.Button
        confirm                    matlab.ui.control.Button
        sessLoc                    matlab.ui.control.EditField
        SESSIONEditFieldLabel      matlab.ui.control.Label
        eventLoc                   matlab.ui.control.EditField
        EVENTSEditFieldLabel       matlab.ui.control.Label
        FOpanel                    matlab.ui.container.Panel
        fileLast                   matlab.ui.control.Button
        fileNext                   matlab.ui.control.Button
        fileLoad                   matlab.ui.control.Button
    end


    %%%%%%%%% TO DO
    %%     f. Loop through sessions and export to NWB
    %%        i. User interface for variables as needed
    %%        ii. Skip behavior for now
    %%     g. Save in NWB directory
    %%     h. Save nwb directory to sessionInfo


    properties (Access = private)
        mainLoc % Description
        sessFILE % Description
        eventLOC
        sessLOC
        nwbLOC
        sessLOGdat
        sessINFO
        curROW
        AllSESS
        curSESS
        channelID
        prog
        procPROG
        locsFILE
    end

    methods (Access = private)

        function foldReset(app , opt)

            switch opt
                case 'EV'
                    mEssage = "RESET RAW Event Folder";
                    tiTLE = "RAW Event Location";
                    uiMESSage = "Select folder with Events file";

                case 'SE'
                    mEssage = "RESET Session Folder";
                    tiTLE = "Session Location";
                    uiMESSage = 'Select folder with Session file';

                case 'NWB'
                    mEssage = "SET NWB Folder";
                    tiTLE = "NWB Save Location";
                    uiMESSage = 'Select folder to save NWB';

            end
            foldSEL = uiconfirm(app.UIFigure, mEssage,...
                tiTLE, "Icon","info");

            switch foldSEL
                case 'OK'
                    nevCheck = 1;
                    while nevCheck
                        [outLOC] = uigetdir('title',uiMESSage);

                        if outLOC == 0
                            ckCan = uiconfirm(app.UIFigure, 'DID you mean to CANCEL?',...
                                "NO Selection?", "Icon","info");

                            switch ckCan
                                case 'OK'
                                    return
                                case 'Cancel'
                                    continue
                            end
                        else
                            nevCheck = 0;
                        end

                    end

                case 'Cancel'
                    return

            end

            switch opt
                case 'EV'
                    app.eventLOC = outLOC;
                    app.eventLoc.Value = app.eventLOC;
                case 'SE'
                    app.sessLOC = outLOC;
                    app.sessLoc.Value = app.sessLOC;
                case 'NWB'
                    app.nwbLOC = outLOC;
                    app.nwbLoc.Value = app.nwbLOC;

            end

        end


        function [foundCSC , timeDIF] = findCSCid(app , timeSTAMP)

            % Go to Event Directory
            cd(app.eventLOC)
            % Locate unique CSC extension files (i.e, CSC1__001, __002)
            cscRepoT = dir('*.ncs');
            cscRepo = {cscRepoT.name};

            fileEls = cellfun(@(x) strsplit(x, {'_','.'}), cscRepo, 'UniformOutput',false);

            cscAllnum = cellfun(@(x) x{1}, fileEls , 'UniformOutput',false);

            uniCSC = unique(cscAllnum);
            useCSC = uniCSC(1);

            allCSCns = transpose(cscRepo(ismember(cscAllnum,useCSC)));

            % Loop through all extension
            micAll = zeros(size(allCSCns));
            ephInd = zeros(size(allCSCns));
            for ci = 1:length(allCSCns)

                [TimestampsCSC, ~, ~, ~,...
                    ~, ~] = Nlx2MatCSC(allCSCns{ci}, [1 1 1 1 1], 1, 1, [] );

                % Create interpolation
                TimestampsINT = nlxEVinterp(app , TimestampsCSC);

                % Create a table with the time difference between nearest value
                % and timestamp
                offSETtime = abs(TimestampsINT - timeSTAMP);
                [micOffset , ephysInd] = min(min(offSETtime));

                micAll(ci) = micOffset;
                ephInd(ci) = ephysInd;

            end
            % Output CSC duplicate with the lowest value
            chckTable = table(allCSCns,micAll,ephInd);
            chckTable.msAll = chckTable.micAll/(1e+6);

            [~ , minREC] = min(chckTable.micAll);

            foundCSC = chckTable.allCSCns{minREC};
            timeDIF = chckTable.msAll(minREC);

        end


        function timeMAT = nlxEVinterp(~ , timeVEC)

            timeMAT = zeros(512,size(timeVEC,2));
            timeMAT(1,:) = timeVEC;
            for ti = 1:size(timeVEC,2)

                if ti == size(timeVEC,2)
                    tmpl = linspace(timeVEC(ti),timeVEC(ti)+16000,511);
                else
                    tmpl = linspace(timeVEC(ti),timeVEC(ti + 1),511);
                end
                tmplt = transpose(tmpl);
                timeMAT(2:512,ti) = tmplt;
            end

        end


        function highlightROW(app)

            allROWS = 1:height(app.UITable.Data);
            allCOLS = width(app.UITable.Data);

            hlRow = uistyle('BackgroundColor',[1 0.6 0.6]);
            nlRow = uistyle('BackgroundColor',[0.98 0.98 0.98]);

            rowSh = repmat(app.curROW,allCOLS,1);
            colSh = transpose(1:allCOLS);

            tallROWS = allROWS(allROWS ~= app.curROW);

            rowSn = reshape(repmat(tallROWS,allCOLS,1),[],1);
            colSn = transpose(repmat(1:allCOLS,1,numel(tallROWS)));

            addStyle(app.UITable,hlRow,'cell',[rowSh,colSh]);

            addStyle(app.UITable,nlRow,'cell',[rowSn,colSn]);

            scroll(app.UITable,'row',app.curROW)

        end


        function uiOUT = confirmMessage(app , stage)

            switch stage
                case 1
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE NWB Folder check .mat file",...
                        'NWB_Process_Folder_Check.mat', "Icon","info");
                case 2
                    uiOUT = uiconfirm(app.UIFigure, "LOCATED NWB",...
                        "NWB INSTALLED", "Icon","info");
                case 3
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE MICRO CSV",...
                        "MICRO CSV", "Icon","info");
                case 4
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE MACRO CSV",...
                        "MACRO CSV", "Icon","info");
                case 5
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE NWB Folder",...
                        "NWB location", "Icon","info");
                case 6
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE Matlab Folder",...
                        "MATLAB location", "Icon","info");
            end

        end


        function [outDATA , sampFrOUT , adBitVal] = CSCLoader(app , tmpTable , elecTYPE, flitER)

            app.procPROG.Message = ['Working on file ', elecTYPE, ' table building'];

            cd(app.eventLOC)

            cscSuffix = getCurCSC(app);

            if strcmp(elecTYPE,'MI')
                eleROWs = tmpTable.label(contains(tmpTable.label,'MI'));
                eleROWi = eleROWs{1};
                eleROWstrs = strsplit(eleROWi,'_');
                eleROWid = eleROWstrs{3}(2:end);
                tmpFnDim = ['CSC',eleROWid,cscSuffix];
            elseif strcmp(elecTYPE,'MA')
                eleROWs = tmpTable.label(contains(tmpTable.label,'MA'));
                eleROWi = eleROWs{1};
                eleROWstrs = strsplit(eleROWi,'_');
                eleROWid = eleROWstrs{3}(2:end);
                tmpFnDim = ['CSC',eleROWid,cscSuffix];
            end

            % tmpEle to get dimesions
            [~, ~, sf, ~,...
                smpGetdim, ~] = Nlx2MatCSC(tmpFnDim, [1 1 1 1 1], 1, 1, [] );

            sampFrOUT = sf(1);

            outDATA = zeros(length(eleROWs),numel(smpGetdim));

            % Loop through channel IDs
            for ti = 1:length(eleROWs)
                eleROWti = eleROWs{ti};
                eleROWtstr = strsplit(eleROWti,'_');
                cscNUM = eleROWtstr{3}(2:end);
                tmpCSC = ['CSC', cscNUM , cscSuffix];

                % Get raw data from NLX
                % 1. Reshape
                % 2.
                % 3. in16
                % 4. filter 0.1 to 1K
                % 5. Get Fs
                % 6. Get adbit value

                app.procPROG.Message = ['Filtering file ', num2str(ti), ' out of ',...
                    num2str(length(eleROWs))];

                [~, ~, ~, ~,...
                    Samples, HeaderCSC] = Nlx2MatCSC(tmpCSC, [1 1 1 1 1], 1, 1, [] );

                adLine = HeaderCSC(contains(HeaderCSC, 'ADBit'));
                adItems = strsplit(adLine{1},' ');
                adBitVal = str2double(adItems{2});

                %                 adbitsVal = adBitVal;
                %                 actVolts = Samples .* adbitsVal; % in Volts

                % Reshape
                reshSamp = reshape(Samples,1,numel(Samples));

                %                 eegPB.Message = sprintf('%s',['Notch Filter Hour ' , num2str(hi)]);
                % Notch filter

                if flitER

                    %%%% LOOP through 60 second chunks -----
                    if strcmp(elecTYPE,'MI')
                        [nlxCHUNKS]= nlxCHUNKfunc(app, reshSamp , sampFrOUT, 1);
                    elseif strcmp(elecTYPE,'MA')
                        [nlxCHUNKS]= nlxCHUNKfunc(app, reshSamp , sampFrOUT, 5);
                    end

                    procCHUNK = nan(size(nlxCHUNKS));
                    for chuk = 1:size(nlxCHUNKS,1)
                        % single chunk
                        tmpChunk = nlxCHUNKS(chuk,:);
                        tmpChunkNoN = tmpChunk(~isnan(tmpChunk));

                        app.procPROG.Message = ['Filtering file ', num2str(ti), ' out of ',...
                            num2str(length(eleROWs)), ':   CHUNK  ' ,...
                            num2str(chuk), ' out of ', num2str(size(nlxCHUNKS,1))];

                        datNOTCH = bandstop(tmpChunkNoN,[59 61],sampFrOUT);

                        % MODIFY IF MI or MA
                        if strcmp(elecTYPE,'MI')
                            % High pass filter 300 Hz
                            hpsamp = highpass(datNOTCH,300,sampFrOUT);
                            % Low pass filter 3000 Hz
                            lpsamp = lowpass(hpsamp, 3000, sampFrOUT);
                        elseif strcmp(elecTYPE, 'MA')
                            % High pass filter 0.1 Hz
                            hpsamp = highpass(datNOTCH,0.1,sampFrOUT);
                            % Low pass filter 1000 Hz
                            lpsamp = lowpass(hpsamp, 1000, sampFrOUT);
                        end

                        if isequal(numel(lpsamp),size(procCHUNK,2))
                            procCHUNK(chuk,:) = lpsamp;
                        else
                            procCHUNK(chuk,1:numel(lpsamp)) = lpsamp;
                        end

                    end

                    % Reshape back to single vector
                    finSamp = reshape(procCHUNK,1,numel(procCHUNK));

                    % Remove nans
                    nfinSamp = finSamp(~isnan(finSamp));

                else

                    nfinSamp = reshSamp;

                end

                % int16
                samp16 = int16(nfinSamp);
                % Stack
                outDATA(ti,:) = samp16;
            end


        end

        function cscSuffix = getCurCSC(app)

            CSCfName = app.UITable.Data.("CSC ID"){app.curROW}; % Check ?

            if ~contains(CSCfName,'_')
                cscSuffix = '.ncs';
            else
                cscSuffix = ['_',extractAfter(CSCfName,strfind(CSCfName,'_'))];
            end

        end

        function matChunk = nlxCHUNKfunc(~, totalVEC , FS , numMins)

            numSeconds = numMins*60;

            oneMin = FS*numSeconds;
            lenVEC = length(totalVEC);
            numCHUNKs = ceil(lenVEC/oneMin);
            chk1blk = numCHUNKs - 1;

            chunkED1 = transpose(reshape(totalVEC(1:chk1blk*numSeconds*FS),numSeconds*FS,chk1blk));
            chunkED1(numCHUNKs,:) = NaN;
            lastChunk = (chk1blk*numSeconds*FS)+1:numel(totalVEC);
            chunkED1(numCHUNKs,1:numel(lastChunk)) = lastChunk;

            matChunk = chunkED1;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentLOC = which('NLX_Session2Neuro_v9');
            filePARts = split(currentLOC,'\');
            newLOC = join(filePARts(1:numel(filePARts)-1),'\');
            resourceLOC = [newLOC{1}, filesep, 'NLX_IO_Code'];
            addpath(genpath(resourceLOC));
        end

        % Button pushed function: fileLoad
        function fileLoadButtonPushed(app, event)

            conOUT = confirmMessage(app,1);

            switch conOUT
                case "Cancel"
                    return
                case "OK"
                    [app.locsFILE , app.mainLoc] = uigetfile('*.mat');

                    if isempty(app.locsFILE) || ~matches('NWB_Process_Folder_Check.mat' , app.locsFILE)
                        uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                        return
                    else

                        cd(app.mainLoc)

                        load(app.locsFILE , 'nwbFilesLOCS')

                        app.sessLoc.Value = nwbFilesLOCS.sessSAVloc;
                        app.eventLoc.Value = nwbFilesLOCS.eventLOC;
                        app.nwbLoc.Value = nwbFilesLOCS.nwbSAVloc;
                        app.eventLOC = app.eventLoc.Value;
                        app.sessLOC = app.sessLoc.Value;
                        app.nwbLOC = app.nwbLoc.Value ;

                        app.confirm.Enable = 'on';
                        app.resetEV.Enable = 'on';
                        app.resetSE.Enable = 'on';
                        app.resetNWB.Enable  = 'on';

                    end
            end


        end

        % Button pushed function: confirm
        function confirmButtonPushed(app, event)

            % CD to session Folder and load session Files
            cd(app.sessLOC)

            matfindck = dir('*.mat');
            matFindckN = {matfindck.name};

            if isempty(matFindckN) || ~matches('allSessionData.mat',matFindckN)
                uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                return
            else
                app.sessFILE = matFindckN{1};
            end

            load(app.sessFILE, "sessionINFo" , "saveSessAll")

            app.sessLOGdat = saveSessAll;
            app.sessINFO = sessionINFo;
            app.AllSESS = 1:length(saveSessAll);
            app.curSESS = app.AllSESS(1);

            if ~isfield(sessionINFo, 'nwbDONE')
                app.sessINFO.nwbDONE = false(length(saveSessAll),1);
                sessionINFo = app.sessINFO;
                save(app.sessFILE,"sessionINFo",'-append')
            else
                app.AllSESS = app.AllSESS(~app.sessINFO.nwbDONE);
                app.curSESS = app.AllSESS(1);
            end

            %%% When process is complete=======================
            app.sessLoc.Editable = 'off';
            app.eventLoc.Editable = 'off';
            app.confirm.Enable = 'off';
            app.resetEV.Enable = 'off';
            app.resetSE.Enable = 'off';
            app.resetNWB.Enable  = 'off';
            app.START.Enable = 'on';


        end

        % Button pushed function: resetSE
        function resetSEButtonPushed(app, event)
            foldReset(app , 'SE')
        end

        % Button pushed function: resetEV
        function resetEVButtonPushed(app, event)
            foldReset(app , 'EV')
        end

        % Button pushed function: fileNext
        function fileNextButtonPushed(app, event)
            tmpROW = app.curROW;
            app.curROW = app.curROW + 1;

            if app.curROW > length(app.AllSESS)
                app.curROW = tmpROW;
                app.curSESS = app.AllSESS(app.curROW);
                return
            else
                highlightROW(app)
                app.curSESS = app.AllSESS(app.curROW);
            end

            % CONSIDER SCROLL

        end

        % Button pushed function: fileLast
        function fileLastButtonPushed(app, event)
            tmpROW = app.curROW;
            app.curROW = app.curROW - 1;

            if app.curROW < 1
                app.curROW = tmpROW;
                app.curSESS = app.AllSESS(app.curROW);
                return
            else
                highlightROW(app)
                app.curSESS = app.AllSESS(app.curROW);
            end
            % CONSIDER SCROLL

        end

        % Button pushed function: importSEEG
        function importSEEGButtonPushed(app, event)

            chanType = {'micro','macro'};
            confiM = [3,4];
            for ci = 1:2
                ct2use = chanType{ci};

                confirmMessage(app,confiM(ci))

                switch ct2use
                    case 'micro'
                        [miFname , miLoc] = uigetfile('*.csv');
                        microLoc = fullfile(miLoc , miFname);
                        microTable = readtable(microLoc);

                        app.channelID.micro = microTable;
                    case 'macro'
                        [maFname , maLoc] = uigetfile('*.csv');
                        macroLoc = fullfile(maLoc , maFname);
                        macroTable = readtable(macroLoc);

                        app.channelID.macro = macroTable;

                end
            end
            channID = app.channelID;
            cd(app.sessLOC)
            save(app.sessFILE,'-append','channID');

            % Activate NWB button
            app.chkNWB.Enable = 'on';
            % Deactivate SEEG label button
            app.importSEEG.Enable = 'off';


        end

        % Button pushed function: chkNWB
        function chkNWBButtonPushed(app, event)

            % Check if on path
            nwbCkt = which('nwbExport');
            % if yes provide message and move on
            if ~isempty(nwbCkt)
                confirmMessage(app, 2)
                app.expNWB.Enable = 'on';
                app.chkNWB.Enable = 'off';
                return
            else
                % if no - ask if installed
                request = uiconfirm(app.UIFigure, "IS NWB Installed??",...
                    "NWB INSTALLATION",'Options',{'YES','NO'});

                switch request
                    case 'YES'
                        % if yes - located folder
                        locFOLD = confirmMessage(app,5);
                        switch locFOLD
                            case{'OK','Cancel'}
                                nwbFOLD = uigetdir();
                                addpath(genpath(nwbFOLD));
                        end

                    case 'NO'
                        matLOC = confirmMessage(app,6);
                        switch matLOC
                            case{'OK','Cancel'}
                                matFOLD = uigetdir();
                                cd(matFOLD);
                                app.prog = uiprogressdlg(app.UIFigure, "Message","Installing NWB",...
                                    'Indeterminate','on');
                                !git clone https://github.com/NeurodataWithoutBorders/matnwb.git

                                nwbdirF1 = dir();
                                nwbdirF2 = {nwbdirF1.name};
                                nwbdirF3 = nwbdirF2(~ismember(nwbdirF2, {'.','..'}));

                                nwbdirF4 = nwbdirF3{contains(nwbdirF3,'matnwb')};
                                nwbdirF5 = [matFOLD , filesep , nwbdirF4];

                                addpath(genpath(nwbdirF5))

                                close(app.prog)

                        end
                end

            end
            app.chkNWB.Enable = 'off';
            app.expNWB.Enable = 'on';


        end

        % Button pushed function: expNWB
        function expNWBButtonPushed(app, event)

            % Run NWB export and save in NWB folder
            % Open Fields in subject / session boxes
            app.SubjectInfoP.Enable = 'on';
            app.SessionInfoP.Enable = 'on';
            app.confirmINFO.Enable = 'on';
            app.confirmINFO.Visible = 'on';

        end

        % Button pushed function: resetNWB
        function resetNWBButtonPushed(app, event)

            foldReset(app , 'NWB')

        end

        % Value changed function: confirmINFO
        function confirmINFOValueChanged(app, event)
            value = app.confirmINFO.Value;

            app.SubjectInfoP.Enable = 'off';
            app.SessionInfoP.Enable = 'off';

            if strcmp(value,'YES')

                app.procPROG = uiprogressdlg(app.UIFigure,'Title','Creating and Saving NWB...',...
                    'Indeterminate','on');

                curSess = app.sessLOGdat{app.curSESS}; % change to session
                % Check if it is behavior or resting state
                cd(app.nwbLOC)

                % From data
                identifier = app.sessINFO.SUBid{1};
                timeStamP = curSess.SessionInfo.ts(curSess.StartIndex);
                sessionstarttime = datetime(timeStamP/1000000,'ConvertFrom',...
                    'posixtime','TimeZone','America/Denver');

                generateCore();

                app.procPROG.Message = 'Core generated';

                uiMICck = uiconfirm(app.UIFigure, "MICRO data collected?",...
                    "MICROwire DATA?","Icon","info",'Options',{'YES','NO'},...
                    'DefaultOption',1,'CancelOption',2);

                switch uiMICck
                    case 'YES'
                        microCheck = true;
                    case 'NO'
                        microCheck = false;
                end

                uiMACck = uiconfirm(app.UIFigure, "MACRO data collected?",...
                    "MACROwire DATA?","Icon","info",'Options',{'YES','NO'},...
                    'DefaultOption',1,'CancelOption',2);

                switch uiMACck
                    case 'YES'
                        macroCheck = true;
                    case 'NO'
                        macroCheck = true;
                end

                % Create NWB with session and subject info
                nwb = NwbFile( ...
                    'session_description', 'EMU patient',...
                    'identifier', [identifier ,'_', num2str(app.curSESS)], ... %
                    'session_start_time', sessionstarttime, ...
                    'general_experimenter', app.ExperimenterDropDown.Value, ...
                    'general_session_id', app.SessionIDDropDown.Value, ...
                    'general_institution', 'UC Anschutz');

                subject = types.core.Subject(...
                    'subject_id', identifier, ...
                    'age', ['P',num2str(app.SubAgeEditField.Value),'Y'], ...
                    'sex',app.SubSexDropDown.Value);

                nwb.general_subject = subject;

                app.procPROG.Message = 'Subject added';

                device = types.core.Device(...
                    'description', 'Atlas', ...
                    'manufacturer', 'Neuralynx');
                device_name = 'NLX';

                nwb.general_devices.set(device_name, device);
                device_link = types.untyped.SoftLink(['/general/devices/' device_name]);

                app.procPROG.Message = 'Device added';

                % Define Number of Depth electrodes and
                % microwires

                % Load in microwire table
                if microCheck
                    miTABo = app.channelID.micro;

                    depEnum = unique(miTABo.depEnum);
                    startChan = miTABo.chanNum(1);
                    stopChan = miTABo.chanNum(height(miTABo));
                    shortN = unique(miTABo.shortN);
                    longN = unique(miTABo.longN);
                    hemi = {miTABo.shortN{1}(1)};
                    numChan = height(miTABo);
                    elecR = {'MI'};

                    miTAB = table(depEnum,startChan,stopChan,shortN,longN,hemi,numChan,elecR);
                else
                    variables = {'depEnum', 'startChan', 'stopChan', 'shortN',...
                        'longN', 'hemi', 'numChan', 'elecR'};
                    miTAB = cell2table(cell(0, length(variables)), 'VariableNames', variables);
                end

                if macroCheck
                    maTAB = app.channelID.macro;
                    maTAB.elecR = repmat({'MA'},height(maTAB),1);
                else
                    variables = {'depEnum', 'startChan', 'stopChan', 'shortN',...
                        'longN', 'hemi', 'numChan', 'elecR'};
                    maTAB = cell2table(cell(0, length(variables)), 'VariableNames', variables);
                end

                allTAB = [miTAB ; maTAB];

                app.procPROG.Message = 'Electrode table initiated';

                % Populate table
                % Add short and long name to columns -
                allIDEtab = table;
                for iDE = 1:height(allTAB)

                    tmpWire = num2str(allTAB.depEnum(iDE));
                    mimatyp = allTAB.elecR{iDE};
                    if strcmp(mimatyp,'MI')
                        descriP = ['electrode group for microwires on DE_' tmpWire];
                    else
                        descriP = ['electrode group for macrocontacts on DE_' tmpWire];
                    end
                    % Long name
                    brainArLocation = allTAB.longN{iDE};
                    % Short name
                    brainArSN = allTAB.shortN{iDE};
                    % hemisphere
                    hemiBA = allTAB.shortN{iDE}(1);
                    % Number of channels per wire
                    nchannels_per_DE = allTAB.numChan(iDE);

                    group_name = [mimatyp, '_DE_' tmpWire];

                    nwb.general_extracellular_ephys.set(group_name, ...
                        types.core.ElectrodeGroup( ...
                        'description', descriP, ...
                        'location', brainArLocation, ...
                        'device', device_link));

                    group_object_view = types.untyped.ObjectView( ...
                        ['/general/extracellular_ephys/' group_name]); % Currently as wire change to micro and macro as devices

                    xcoord = zeros(nchannels_per_DE,1);
                    ycoord = zeros(nchannels_per_DE,1);
                    zcoord = zeros(nchannels_per_DE,1);
                    imp = zeros(nchannels_per_DE,1);
                    location = repmat({brainArLocation},nchannels_per_DE,1);
                    filtering = cell(nchannels_per_DE,1); %%%%% IF MACRO 0.1 to 1000 kHz - filter
                    shortBAn = repmat({brainArSN},nchannels_per_DE,1);
                    hemisph = repmat({hemiBA},nchannels_per_DE,1);
                    wireID = repmat(allTAB.depEnum(iDE),nchannels_per_DE,1);
                    channID = zeros(nchannels_per_DE,1);
                    group = [];% Wire ID
                    label = cell(nchannels_per_DE,1);% Chan ID

                    eleARRAY = allTAB.startChan(iDE):allTAB.stopChan(iDE);
                    for ielec = 1:nchannels_per_DE
                        xcoord(ielec) = NaN;
                        ycoord(ielec) = NaN;
                        zcoord(ielec) = NaN;
                        imp(ielec) = NaN;
                        group = [group ; group_object_view]; %#ok<AGROW>
                        if strcmp(mimatyp,'MI')
                            filtering{ielec} = '300-3000';
                        else
                            filtering{ielec} = '0.1-1000';
                        end
                        channID(ielec) = eleARRAY(ielec);
                        label{ielec} = [mimatyp,'_D',tmpWire,'_C',num2str(eleARRAY(ielec))];
                    end

                    % Create table or each iDE
                    tbl = table(xcoord, ycoord, zcoord, imp, location, ...
                        filtering, channID, shortBAn, hemisph, ...
                        wireID, group,label);

                    allIDEtab = [allIDEtab ; tbl]; %#ok<AGROW>

                end

                app.procPROG.Message = 'Electrode table created';

                electrode_table = util.table2nwb(allIDEtab, 'all electrodes');
                nwb.general_extracellular_ephys_electrodes = electrode_table;

                electrodes_object_view = types.untyped.ObjectView( ...
                    '/general/extracellular_ephys/electrodes');

                % Add timestamps
                if microCheck

                    mifieldLOC = find(contains(allIDEtab.label,'MI'))-1;

                    electrode_table_region1 = types.hdmf_common.DynamicTableRegion( ...
                        'table', electrodes_object_view, ...
                        'description', 'all micro electrodes', ...
                        'data', mifieldLOC);

                    % Get sampling frequency, data (unscaled, unfurled and
                    % filtered), adbitconvert, int16

                    filterFlag = 0;
                    [microDAT , micSF , miADbitval] = CSCLoader(app , allIDEtab , 'MI', filterFlag);

                    app.procPROG.Message = 'micro data processed';

                    cd(app.nwbLOC)

                    electrical_series1 = types.core.ElectricalSeries( ...
                        'starting_time', 0.0, ... % seconds
                        'starting_time_rate', micSF, ... % Hz
                        'data', microDAT, ...  %
                        'electrodes', electrode_table_region1, ...
                        'data_unit', 'volts', ...
                        'data_conversion', miADbitval, ...
                        'description', ['Micro data that needs to be converted' ...
                        'to volts with conversion - from int16 to float']);
                end

                if macroCheck

                    mafieldLOC = find(contains(allIDEtab.label,'MA'))-1;

                    electrode_table_region2 = types.hdmf_common.DynamicTableRegion( ...
                        'table', electrodes_object_view, ...
                        'description', 'all macro electrodes', ...
                        'data', mafieldLOC);

                    [macroDAT , macSF , maADbitval] = CSCLoader(app , allIDEtab , 'MA', filterFlag);

                    app.procPROG.Message = 'macro data processed';

                    cd(app.nwbLOC)

                    electrical_series2 = types.core.ElectricalSeries( ...
                        'starting_time', 0.0, ... % seconds
                        'starting_time_rate', macSF, ... % Hz
                        'data', macroDAT, ...  % Number of wires in ROW randn(12, 3000) microDAT
                        'data_conversion', maADbitval, ...
                        'electrodes', electrode_table_region2, ...  %%%%%%% CHANGED from electrodes
                        'data_unit', 'volts', ...
                        'description', ['Macro data that needs to be converted' ...
                        'to volts with conversion - from int16 to float']);
                end

                nwb.acquisition.set('MicroWireSeries', electrical_series1);
                nwb.acquisition.set('MacroWireSeries', electrical_series2);

                app.procPROG.Message = 'Electrode Series added';

                sessTYPE = app.UITable.Data.SessID{app.curROW};

                switch sessTYPE
                    case 'Resting State'

                        allTStamps = app.sessLOGdat{app.curSESS}.SessionInfo.ts; % change to session
                        indStart = allTStamps(app.sessLOGdat{app.curSESS}.StartIndex); % change to session
                        indStop = allTStamps(app.sessLOGdat{app.curSESS}.StopIndex); % change to session
                        % RAW Time in Unix
                        % timeREcord = datetime(indStart/1000000,'ConvertFrom','posixtime','TimeZone','America/Denver');
                        % Find start index in ephys
                        % Create time vec
                        % Get time (in seconds) of timestamp
                        cscSuffix = getCurCSC(app);

                        if microCheck
                            eleROWs = allIDEtab.label(contains(allIDEtab.label,'MI'));
                        else
                            eleROWs = allIDEtab.label(contains(allIDEtab.label,'MA'));
                        end
                        eleROWi = eleROWs{1};
                        eleROWstrs = strsplit(eleROWi,'_');
                        eleROWid = eleROWstrs{3}(2:end);
                        tmpFnDim = ['CSC',eleROWid,cscSuffix];

                        cd(app.eventLOC)

                        [TimestampsCSC, ~, sf, ~,...
                            ~, ~] = Nlx2MatCSC(tmpFnDim, [1 1 1 1 1], 1, 1, [] );
                        sampFrOUT = sf(1);
                        cd(app.nwbLOC)
                        % Create interpolation
                        TimestampsINT = nlxEVinterp(app , TimestampsCSC);
                        TimestampsINT2 = reshape(TimestampsINT,1,numel(TimestampsINT));
                        % Create a table with the time difference between nearest value
                        % and timestamp
                        timAXIS = 0:1/sampFrOUT:(length(TimestampsINT2)-1)/sampFrOUT;

                        offSETStart = abs(TimestampsINT2 - indStart);
                        [~ , startOind] = min(offSETStart);

                        offSETStop = abs(TimestampsINT2 - indStop);
                        [~ , stopOind] = min(offSETStop);
                        % Start of ephys will be 0.0
                        startTIMES = timAXIS(startOind);
                        stopTIMES = timAXIS(stopOind);
                        idNUMS = 0:length(startTIMES)-1;
                        behNUMS = false(1,length(startTIMES));

                        % Change to Epochs nwb.intervals.epochs - regions
                        % of time

                        trials = types.core.TimeIntervals( ...
                            'colnames', {'start_time', 'stop_time','correct'}, ...
                            'description', 'trial data and properties', ...
                            'id', types.hdmf_common.ElementIdentifiers('data', idNUMS), ...
                            'start_time', types.hdmf_common.VectorData('data', startTIMES, ...
                            'description','start time of trial'), ...
                            'stop_time', types.hdmf_common.VectorData('data', stopTIMES, ...
                            'description','end of each trial'), ...
                            'correct', types.hdmf_common.VectorData('data', behNUMS, ...
                            'description', 'whether the trial was correct'));

                        nwb.intervals_trials = trials;

                    case 'Behavior'
                        allTStamps = app.sessLOGdat{app.curSESS}.SessionInfo.ts; % change to session
                        allTSids = app.sessLOGdat{app.curSESS}.SessionInfo.tInfo; % change to session
                        indStart = allTStamps(app.sessLOGdat{app.curSESS}.StartIndex); % change to session

                        %%%%%% CHECK IF START VALUE IS 'STARTING'...

                        indStartVal = app.sessLOGdat{app.curSESS}.StartIndex; % change to session
                        indStopVal = app.sessLOGdat{app.curSESS}.StopIndex; % change to session

                        % Check start
                        if matches(allTSids{indStartVal},'Starting Recording')
                            stimStart = indStartVal+1;
                        else
                            stimStart = indStartVal;
                        end

                        if matches(allTSids{indStopVal},'Stopping Recording')
                            stimEnd = indStopVal-1;
                        else
                            stimEnd = indStopVal;
                        end

                        stimulusEvents = allTStamps(stimStart:stimEnd);

                        timeREcordStim = datetime(stimulusEvents/1000000,'ConvertFrom','posixtime','TimeZone','America/Denver');

                        timeREcordStart = datetime(indStart/1000000,'ConvertFrom','posixtime','TimeZone','America/Denver');

                        eventTTLs = seconds(timeREcordStim - timeREcordStart);

                        % Placeholder for event strings
                        eventSTRs = allTSids(stimStart:stimEnd);

                        nwbEvents = types.core.AnnotationSeries('data', eventSTRs,...
                            'data_unit', 'NA', 'timestamps', eventTTLs, 'description', 'TTLs');

                        nwb.acquisition.set('events', nwbEvents)

                end

                nwbNAME = [app.sessINFO.SUBid{1},'_','Session_',num2str(app.curSESS),'_raw.nwb'];

                nwbExport(nwb, nwbNAME)

                % Update Session file
                cd(app.sessLOC)
                nwbINFO.update = 1;
                nwbINFO.dateU = datestr(datetime);
                nwbINFO.fileLOC = app.nwbLOC;
                nwbINFO.fileName = nwbNAME;
                save(app.sessFILE,"nwbINFO" ,'-append')
                cd(app.nwbLOC)

                close(app.procPROG)

                % Reset
                app.SubjectInfoP.Enable = 'on';
                app.SessionInfoP.Enable = 'on';
                app.confirmINFO.Value = 'NO';

                % Remove Row from table
                tmpTable = app.UITable.Data;
                tmpTable(app.curROW,:) = [];
                app.sessINFO.nwbDONE(app.curSESS) = true; % Change to session

                % Clean up AllSESS % if CurSESS 4 and all SESS 8 =
                % [1 2 3 5 6 7 8] - use CurSESS = ALLSESS(curROW)
                app.AllSESS(app.curROW) = [];

                if app.curROW > length(app.AllSESS)
                    app.curROW = length(app.AllSESS);
                end

                if isempty(app.AllSESS)
                    app.curSESS = 0;
                else
                    app.curSESS = app.AllSESS(app.curROW);
                end

                % Update Table
                app.UITable.Data = tmpTable;
                % save session file
                cd(app.sessLOC)
                sessionINFo = app.sessINFO;
                save(app.sessFILE,"sessionINFo" ,'-append')

            else
                app.SubjectInfoP.Enable = 'on';
                app.SessionInfoP.Enable = 'on';
            end

        end

        % Button pushed function: START
        function STARTButtonPushed(app, event)
            % UI WAIT
            app.prog = uiprogressdlg(app.UIFigure, "Message","Setting up Table",...
                'Indeterminate','on');

            % Save new information:
            cd(app.sessLOC)
            load(app.sessFILE,"sessionINFo")
            sessionINFo.nwbLOC = app.nwbLOC;
            sessionINFo.eventLOC = app.eventLOC;
            sessionINFo.sessiLOC = app.sessLOC;

            app.sessINFO = sessionINFo;

            save(app.sessFILE,"sessionINFo" ,'-append')

            % Load in first panel

            % Activate Session Panel
            app.SPpanel.Enable = 'on';

            % Check if Data table is available
            cd(app.sessLOC)
            datTABchk = matfile(app.sessFILE);
            datTABids = whos(datTABchk);
            datTABnames = {datTABids.name};
            datTABflag = ismember("sessINFOtable",datTABnames);

            if ~datTABflag
                % Populate Session Table
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                [rows,cols] = size(app.sessLOGdat);
                if cols > rows
                    sessLOGdat_T = transpose(app.sessLOGdat);
                else
                    sessLOGdat_T = app.sessLOGdat;
                end


                sessNUM = transpose(1:length(sessLOGdat_T)); % NEW
                nevFileN = cell(size(sessLOGdat_T)); % NEW
                sessID = cell(size(sessLOGdat_T));
                dateREC = cell(size(sessLOGdat_T));
                timeREC = cell(size(sessLOGdat_T));
                startTime = zeros(size(sessLOGdat_T));
                startInd = zeros(size(sessLOGdat_T));
                cscID = cell(size(sessLOGdat_T));
                offsetMS = zeros(size(sessLOGdat_T));
                durMins = zeros(size(sessLOGdat_T));
                numEventS = zeros(size(sessLOGdat_T));

                for ti = 1:length(app.sessLOGdat)
                    % Row 1: Session Number
                    % Row 2: Event NEV file name
                    % Row 3: SessionID = SessType
                    % Row 4: Date recorded = DateRec
                    % Row 5: Time recorded = DateRec
                    % Row 6: StartIndex = StartIndex
                    % Row 7: StartTime = SessionInfo.ts(StartIndex)
                    % Row 8: CSC ID = ?
                    % Row 9: Duration offset in milliseconds
                    % Row 10: Duration of session in minutes
                    % Row 11: Number of Events
                    sessID{ti} = app.sessLOGdat{ti}.SessType;
                    dateREC{ti} = datestr(app.sessLOGdat{ti}.DateRec,'mm/dd/yyyy');
                    timeREC{ti} = datestr(app.sessLOGdat{ti}.DateRec,"HH:MM:SS");
                    startInd(ti) = app.sessLOGdat{ti}.StartIndex;
                    startTime(ti) = app.sessLOGdat{ti}.SessionInfo.ts(startInd(ti));

                    tmpHeader = app.sessLOGdat{ti}.SessionInfo.header;
                    tmpTEXT = split(tmpHeader(6),'\');

                    nevFileN{ti} = tmpTEXT{length(tmpTEXT)};

                    % Identify CSC file
                    app.prog.Message = ['Working on file ', num2str(ti), ' of file ',...
                        num2str(length(app.sessLOGdat)) ];

                    [cscNUM , offSet] = findCSCid(app , startTime(ti));

                    cscID{ti} = cscNUM;
                    offsetMS(ti) = int16(offSet);

                    startT = datetime(app.sessLOGdat{ti}.StartTime/1000000,...
                        'ConvertFrom','posixtime','TimeZone','America/Denver');
                    stopT = datetime(app.sessLOGdat{ti}.StopTime/1000000,...
                        'ConvertFrom','posixtime','TimeZone','America/Denver');
                    durMins(ti) = int16(ceil(minutes(stopT - startT)));

                    numEventS(ti) = app.sessLOGdat{ti}.StopIndex - app.sessLOGdat{ti}.StartIndex;

                end

                close(app.prog)

                datTable = table(sessNUM, nevFileN , sessID, dateREC, timeREC, startTime, startInd,...
                    cscID, offsetMS, durMins, numEventS, 'VariableNames',app.UITable.ColumnName);

                % Check NWB done
                datTable2 = datTable(~app.sessINFO.nwbDONE,:);

                % SAVE TABLE WITH INFO to SESSION app and CHECK for %
                % 08/03/2021
                sessINFOtable = datTable2;
                cd(app.sessLOC)
                save(app.sessFILE,"sessINFOtable",'-append');
                app.UITable.Data = datTable2;
            else
                load(app.sessFILE,"sessINFOtable")
                updateTable = sessINFOtable(~app.sessINFO.nwbDONE,:);
                app.UITable.Data = updateTable;
                close(app.prog)
            end


            % Open up Neuro Processes
            % Check if data are present in .mat file
            cd(app.sessLOC)
            exampleObject = matfile(app.sessFILE);

            % MACRO AND MICRO
            varlist = who(exampleObject);
            seFcheck = contains(varlist, 'channID');

            % If present - load it in but leave button off
            if any(seFcheck)
                load(app.sessFILE,"channID")
                app.channelID = channID;
            else % If not present - have user navigate and load
                app.importSEEG.Enable = 'on';
            end

            % Open up Next file
            app.fileNext.Enable = 'on';
            app.fileLast.Enable = 'on';

            % Turn on NWB
            app.chkNWB.Enable = 'on';

            % Highlight current row
            app.curROW = 1;
            highlightROW(app);

            app.START.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FOpanel = uipanel(app.UIFigure);
            app.FOpanel.Title = 'FILE OPTIONS';
            app.fileLoad = uibutton(app.FOpanel, 'push');
            app.fileLoad.ButtonPushedFcn = createCallbackFcn(app, @fileLoadButtonPushed, true);
            app.fileLoad.Icon = 'FolderCREATE.png';
            app.fileLoad.Text = '';
            app.fileNext = uibutton(app.FOpanel, 'push');
            app.fileNext.ButtonPushedFcn = createCallbackFcn(app, @fileNextButtonPushed, true);
            app.fileNext.Enable = 'off';
            app.fileNext.Text = 'Next File';
            app.fileLast = uibutton(app.FOpanel, 'push');
            app.fileLast.ButtonPushedFcn = createCallbackFcn(app, @fileLastButtonPushed, true);
            app.fileLast.Enable = 'off';
            app.fileLast.Text = 'Last File';
            app.DLpanel = uipanel(app.UIFigure);
            app.DLpanel.Title = 'DIRECTORY LOCATIONS';
            app.EVENTSEditFieldLabel = uilabel(app.DLpanel);
            app.EVENTSEditFieldLabel.Text = 'EVENTS';
            app.eventLoc = uieditfield(app.DLpanel, 'text');
            app.SESSIONEditFieldLabel = uilabel(app.DLpanel);
            app.SESSIONEditFieldLabel.Text = 'SESSION';
            app.sessLoc = uieditfield(app.DLpanel, 'text');
            app.confirm = uibutton(app.DLpanel, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirm.Enable = 'off';
            app.confirm.Text = {'CONFIRM'; 'Directories'; ''};
            app.resetEV = uibutton(app.DLpanel, 'push');
            app.resetEV.ButtonPushedFcn = createCallbackFcn(app, @resetEVButtonPushed, true);
            app.resetEV.Enable = 'off';
            app.resetEV.Text = 'Reset';
            app.resetSE = uibutton(app.DLpanel, 'push');
            app.resetSE.ButtonPushedFcn = createCallbackFcn(app, @resetSEButtonPushed, true);
            app.resetSE.Enable = 'off';
            app.resetSE.Text = 'Reset';
            app.NWBLabel = uilabel(app.DLpanel);
            app.NWBLabel.Text = 'NWB';
            app.nwbLoc = uieditfield(app.DLpanel, 'text');
            app.resetNWB = uibutton(app.DLpanel, 'push');
            app.resetNWB.ButtonPushedFcn = createCallbackFcn(app, @resetNWBButtonPushed, true);
            app.resetNWB.Enable = 'off';
            app.resetNWB.Text = 'Reset';
            app.START = uibutton(app.DLpanel, 'push');
            app.START.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.START.Enable = 'off';
            app.START.Text = {'START'; 'Process'};
            app.SPpanel = uipanel(app.UIFigure);
            app.SPpanel.Enable = 'off';
            app.SPpanel.Title = 'SESSION PROCESSING';
            app.UITable = uitable(app.SPpanel);
            app.UITable.ColumnName = {'Sess#'; 'EventNEV'; 'SessID'; 'RecDate'; 'RecTime'; 'StartT'; 'StartI'; 'CSC ID'; 'offSET'; 'DurMins'; 'Event#'};
            app.UITable.RowName = {};
            app.step2 = uilabel(app.SPpanel);
            app.step2.Text = 'STEP 2:';
            app.step3 = uilabel(app.SPpanel);
            app.step3.Text = 'STEP 3:';
            app.importSEEG = uibutton(app.SPpanel, 'push');
            app.importSEEG.ButtonPushedFcn = createCallbackFcn(app, @importSEEGButtonPushed, true);
            app.importSEEG.Enable = 'off';
            app.importSEEG.Text = 'Import Neuro Labels';
            app.expNWB = uibutton(app.SPpanel, 'push');
            app.expNWB.ButtonPushedFcn = createCallbackFcn(app, @expNWBButtonPushed, true);
            app.expNWB.Enable = 'off';
            app.expNWB.Text = 'Export to NWB';
            app.chkNWB = uibutton(app.SPpanel, 'push');
            app.chkNWB.ButtonPushedFcn = createCallbackFcn(app, @chkNWBButtonPushed, true);
            app.chkNWB.Enable = 'off';
            app.chkNWB.Text = 'Check for NWB';
            app.step1 = uilabel(app.SPpanel);
            app.step1.Text = 'STEP 1:';
            app.SessionInfoP = uipanel(app.UIFigure);
            app.SessionInfoP.Enable = 'off';
            app.SessionInfoP.Title = 'Session Info';
            app.SessionIDDropDownLabel = uilabel(app.SessionInfoP);
            app.SessionIDDropDownLabel.Text = 'SessionID';
            app.SessionIDDropDown = uidropdown(app.SessionInfoP);
            app.SessionIDDropDown.Items = {'Learning', 'Recognition', 'FaceEmotion', 'PrisonersDilemma', 'Interoception', 'SleepWake', 'RestingState', ''};
            app.SessionIDDropDown.Value = 'Recognition';
            app.ExperimenterDropDownLabel = uilabel(app.SessionInfoP);
            app.ExperimenterDropDownLabel.Text = 'Experimenter';
            app.ExperimenterDropDown = uidropdown(app.SessionInfoP);
            app.ExperimenterDropDown.Items = {'M. Darwin', 'A. Tekriwal', 'R. Tien', 'M. Moe', 'J. Thompson'};
            app.ExperimenterDropDown.Value = 'M. Darwin';
            app.SubjectInfoP = uipanel(app.UIFigure);
            app.SubjectInfoP.Enable = 'off';
            app.SubjectInfoP.Title = 'Subject Info';
            app.SubAgeEditFieldLabel = uilabel(app.SubjectInfoP);
            app.SubAgeEditFieldLabel.Text = 'Sub. Age';
            app.SubAgeEditField = uieditfield(app.SubjectInfoP, 'numeric');
            app.SubSexDropDownLabel = uilabel(app.SubjectInfoP);
            app.SubSexDropDownLabel.Text = 'Sub. Sex';
            app.SubSexDropDown = uidropdown(app.SubjectInfoP);
            app.SubSexDropDown.Items = {'F', 'M'};
            app.SubSexDropDown.Value = 'F';
            app.CONFIRMINFOSwitchLabel = uilabel(app.UIFigure);
            app.CONFIRMINFOSwitchLabel.Visible = 'off';
            app.CONFIRMINFOSwitchLabel.Text = 'CONFIRM INFO';
            app.confirmINFO = uiswitch(app.UIFigure, 'slider');
            app.confirmINFO.Items = {'NO', 'YES'};
            app.confirmINFO.ValueChangedFcn = createCallbackFcn(app, @confirmINFOValueChanged, true);
            app.confirmINFO.Enable = 'off';
            app.confirmINFO.Visible = 'off';
            app.confirmINFO.Value = 'NO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_Session2Neuro_v10

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end