classdef NLX_Session2Neuro_v18 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ERRORCheckingPanel         matlab.ui.container.Panel
        UndoElectrodeLabelsButton  matlab.ui.control.Button
        confirmINFO                matlab.ui.control.Switch
        CONFIRMINFOSwitchLabel     matlab.ui.control.Label
        SubjectInfoP               matlab.ui.container.Panel
        SubSexDropDown             matlab.ui.control.DropDown
        SubSexDropDownLabel        matlab.ui.control.Label
        SubAgeEditField            matlab.ui.control.NumericEditField
        SubAgeEditFieldLabel       matlab.ui.control.Label
        SessionInfoP               matlab.ui.container.Panel
        ExperimenterDropDown       matlab.ui.control.DropDown
        ExperimenterDropDownLabel  matlab.ui.control.Label
        SessionIDDropDown          matlab.ui.control.DropDown
        SessionIDDropDownLabel     matlab.ui.control.Label
        SPpanel                    matlab.ui.container.Panel
        step1                      matlab.ui.control.Label
        chkNWB                     matlab.ui.control.Button
        expNWB                     matlab.ui.control.Button
        importSEEG                 matlab.ui.control.Button
        step3                      matlab.ui.control.Label
        step2                      matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        DLpanel                    matlab.ui.container.Panel
        START                      matlab.ui.control.Button
        resetNWB                   matlab.ui.control.Button
        nwbLoc                     matlab.ui.control.EditField
        NWBLabel                   matlab.ui.control.Label
        resetSE                    matlab.ui.control.Button
        resetEV                    matlab.ui.control.Button
        confirm                    matlab.ui.control.Button
        sessLoc                    matlab.ui.control.EditField
        SESSIONEditFieldLabel      matlab.ui.control.Label
        eventLoc                   matlab.ui.control.EditField
        EVENTSEditFieldLabel       matlab.ui.control.Label
        FOpanel                    matlab.ui.container.Panel
        fileLast                   matlab.ui.control.Button
        fileNext                   matlab.ui.control.Button
        fileLoad                   matlab.ui.control.Button
    end


    %%%%%%%%% TO DO
    %     f. Loop through sessions and export to NWB
    %        i. User interface for variables as needed
    %        ii. Skip behavior for now
    %     g. Save in NWB directory
    %     h. Save nwb directory to sessionInfo


    properties (Access = private)
        mainLoc % Description
        sessFILE % Description
        eventLOC
        sessLOC
        nwbLOC
        sessLOGdat
        sessINFO
        curROW
        AllSESS
        curSESS
        channelID
        prog
        procPROG
        locsFILE
        microCheck
        macroCheck
    end

    methods (Access = private)

        function foldReset(app , opt)

            switch opt
                case 'EV'
                    mEssage = "RESET RAW Event Folder";
                    tiTLE = "RAW Event Location";
                    uiMESSage = "Select folder with Events file";

                case 'SE'
                    mEssage = "RESET Session Folder";
                    tiTLE = "Session Location";
                    uiMESSage = 'Select folder with Session file';

                case 'NWB'
                    mEssage = "SET NWB Folder";
                    tiTLE = "NWB Save Location";
                    uiMESSage = 'Select folder to save NWB';

            end
            foldSEL = uiconfirm(app.UIFigure, mEssage,...
                tiTLE, "Icon","info");

            switch foldSEL
                case 'OK'
                    nevCheck = 1;
                    while nevCheck
                        [outLOC] = uigetdir('title',uiMESSage);

                        if outLOC == 0
                            ckCan = uiconfirm(app.UIFigure, 'DID you mean to CANCEL?',...
                                "NO Selection?", "Icon","info");

                            switch ckCan
                                case 'OK'
                                    return
                                case 'Cancel'
                                    continue
                            end
                        else
                            nevCheck = 0;
                        end

                    end

                case 'Cancel'
                    return

            end

            switch opt
                case 'EV'
                    app.eventLOC = outLOC;
                    app.eventLoc.Value = app.eventLOC;
                case 'SE'
                    app.sessLOC = outLOC;
                    app.sessLoc.Value = app.sessLOC;
                case 'NWB'
                    app.nwbLOC = outLOC;
                    app.nwbLoc.Value = app.nwbLOC;

            end

        end


        function [foundCSC , timeDIF] = findCSCid(app , timeSTAMP)

            % Go to Event Directory
            cd(app.eventLOC)
            % Locate unique CSC extension files (i.e, CSC1__001, __002)
            cscRepoT = dir('*.ncs');
            cscRepo = {cscRepoT.name};

            fileEls = cellfun(@(x) strsplit(x, {'_','.'}), cscRepo, 'UniformOutput',false);

            cscAllnum = cellfun(@(x) x{1}, fileEls , 'UniformOutput',false);

            uniCSC = unique(cscAllnum);
            useCSC = uniCSC(1);

            allCSCns = transpose(cscRepo(ismember(cscAllnum,useCSC)));

            % Loop through all extension
            micAll = zeros(size(allCSCns));
            ephInd = zeros(size(allCSCns));
            for ci = 1:length(allCSCns)

                [TimestampsCSC, ~, ~, ~,...
                    ~, ~] = Nlx2MatCSC(allCSCns{ci}, [1 1 1 1 1], 1, 1, [] );

                % Create interpolation
                TimestampsINT = nlxEVinterp(app , TimestampsCSC);

                % Create a table with the time difference between nearest value
                % and timestamp
                offSETtime = abs(TimestampsINT - timeSTAMP);
                [micOffset , ephysInd] = min(min(offSETtime));

                micAll(ci) = micOffset;
                ephInd(ci) = ephysInd;

            end
            % Output CSC duplicate with the lowest value
            chckTable = table(allCSCns,micAll,ephInd);
            chckTable.msAll = chckTable.micAll/(1e+6);

            [~ , minREC] = min(chckTable.micAll);

            foundCSC = chckTable.allCSCns{minREC};
            timeDIF = chckTable.msAll(minREC);

        end


        function timeMAT = nlxEVinterp(~ , timeVEC)

            timeMAT = zeros(512,size(timeVEC,2));
            timeMAT(1,:) = timeVEC;
            for ti = 1:size(timeVEC,2)

                if ti == size(timeVEC,2)
                    tmpl = linspace(timeVEC(ti),timeVEC(ti)+16000,511);
                else
                    tmpl = linspace(timeVEC(ti),timeVEC(ti + 1),511);
                end
                tmplt = transpose(tmpl);
                timeMAT(2:512,ti) = tmplt;
            end

        end


        function highlightROW(app)

            allROWS = 1:height(app.UITable.Data);
            allCOLS = width(app.UITable.Data);

            hlRow = uistyle('BackgroundColor',[1 0.6 0.6]);
            nlRow = uistyle('BackgroundColor',[0.98 0.98 0.98]);

            rowSh = repmat(app.curROW,allCOLS,1);
            colSh = transpose(1:allCOLS);

            tallROWS = allROWS(allROWS ~= app.curROW);

            rowSn = reshape(repmat(tallROWS,allCOLS,1),[],1);
            colSn = transpose(repmat(1:allCOLS,1,numel(tallROWS)));

            addStyle(app.UITable,hlRow,'cell',[rowSh,colSh]);

            addStyle(app.UITable,nlRow,'cell',[rowSn,colSn]);

            scroll(app.UITable,'row',app.curROW)

        end


        function uiOUT = confirmMessage(app , stage)

            switch stage
                case 1
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE NWB Folder check .mat file",...
                        'NWB_Process_Folder_Check.mat', "Icon","info");
                case 2
                    uiOUT = uiconfirm(app.UIFigure, "LOCATED NWB",...
                        "NWB INSTALLED", "Icon","info");
                case 3
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE MICRO CSV",...
                        "MICRO CSV", "Icon","info");
                case 4
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE MACRO CSV",...
                        "MACRO CSV", "Icon","info");
                case 5
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE NWB Folder",...
                        "NWB location", "Icon","info");
                case 6
                    uiOUT = uiconfirm(app.UIFigure, "LOCATE Matlab Folder",...
                        "MATLAB location", "Icon","info");
            end

        end


        function [outDATA , sampFrOUT , adBitVal, outTIME] = CSCLoader(app , tmpTable , elecTYPE)

            % sessLOGdat

            app.procPROG.Message = ['Working on file ', elecTYPE, ' table building'];

            cd(app.eventLOC)

            cscSuffix = getCurCSC(app);

            if strcmp(elecTYPE,'MI')
                eleROWs = tmpTable.label(contains(tmpTable.label,'MI'));
                eleROWi = eleROWs{1};
                eleROWstrs = strsplit(eleROWi,'_');
                eleROWid = eleROWstrs{3}(2:end);
                tmpFnDim = ['CSC',eleROWid,cscSuffix];
            elseif strcmp(elecTYPE,'MA')
                eleROWs = tmpTable.label(contains(tmpTable.label,'MA'));
                eleROWi = eleROWs{1};
                eleROWstrs = strsplit(eleROWi,'_');
                eleROWid = eleROWstrs{3}(2:end);
                tmpFnDim = ['CSC',eleROWid,cscSuffix];
            end

            % tmpEle to get dimesions
            % Get Timestamps
            % Create all ts vector
            [TimestampsCSC, ~, sf, ~,...
                ~, ~] = Nlx2MatCSC(tmpFnDim, [1 1 1 1 1], 1, 1, [] );
            TimestampsINT = nlxEVinterp(app , TimestampsCSC);
            TimStpRsh = reshape(TimestampsINT,1,numel(TimestampsINT));
            sampFrOUT = sf(1);

            % Trim data container
            %             sampDUR = app.sessLOGdat{1}.StopTime - app.sessLOGdat{1}.StartTime;
            %             minDUR = (sampDUR/1000000)/60; % 1000000 = microseconds : convert to seconds
            [~,startIND] = min(abs(TimStpRsh - app.sessLOGdat{app.curSESS}.StartTime));
            [~,stopIND] = min(abs(TimStpRsh - app.sessLOGdat{app.curSESS}.StopTime));

            % REMOVE BUFFER ------------------ 08/05/2023 -- JAT

            % startBUFF = startIND - 5000000;
            % if startBUFF < 0
            %     startBUFF = startIND;
            % end
            %
            % stopBUFF = stopIND + 5000000;
            % if stopBUFF > stopIND
            %     stopBUFF = stopIND;
            % end

            outTIME = TimStpRsh(startIND:stopIND);

            outDATA = zeros(length(eleROWs),numel(startIND:stopIND));

            % Loop through channel IDs
            for ti = 1:length(eleROWs)
                eleROWti = eleROWs{ti};
                eleROWtstr = strsplit(eleROWti,'_');
                cscNUM = eleROWtstr{3}(2:end);
                tmpCSC = ['CSC', cscNUM , cscSuffix];

                % Get raw data from NLX

                app.procPROG.Message = ['Loading file ', num2str(ti), ' out of ',...
                    num2str(length(eleROWs))];

                try
                    [~, ~, ~, ~,...
                        Samples, HeaderCSC] = Nlx2MatCSC(tmpCSC, [1 1 1 1 1], 1, 1, [] );

                catch

                    disp(['ERROR on ', tmpCSC]);

                end
                adLine = HeaderCSC(contains(HeaderCSC, 'ADBit'));
                adItems = strsplit(adLine{1},' ');
                adBitVal = str2double(adItems{2});
                %  adbitsVal = adBitVal;
                %  actVolts = Samples .* adBitVal; % in Volts
                % Reshape
                reshSamp = reshape(Samples,1,numel(Samples));
                nfinSamp = reshSamp;
                % int16
                samp16 = int16(nfinSamp);
                % Stack
                outDATA(ti,:) = samp16(startIND:stopIND);
            end


        end

        function cscSuffix = getCurCSC(app)

            CSCfName = app.UITable.Data.("CSC ID"){app.curROW}; % Check ?

            if ~contains(CSCfName,'_')
                cscSuffix = '.ncs';
            else
                cscSuffix = ['_',extractAfter(CSCfName,strfind(CSCfName,'_'))];
            end

        end


        function [filterData , nFs] = mmfilterFun(app , mWireData , sFs ,  wireType)
            % Extract relevant NWB info
            if wireType == 1
                elecTYPE = 'MAcro';
            else
                elecTYPE = 'MIcro';
            end
            app.procPROG.Message = ['Initiating Filtering for ', elecTYPE, ' data'];
            % Sampling frequency
            % Access the struct names in the NWB acquisition field
            mwireData = mWireData;

            Fs = sFs;

            % Extract channel data
            % Extract vector of data
            % Extract channel of interest

            if wireType == 1
                % t = mwNWB.acquisition.get('MacroWireSeries').data(1,:);
                tmpMWire = mwireData(1,:);
                numCols = ceil(size(tmpMWire,2)/8);
                filterData = zeros(size(mwireData,1),...
                    numCols,'int16');
            else
                ALLchannelData = mwireData;
                filterData = zeros(size(ALLchannelData),'int16');
            end

            % loop through channel data
            for ci = 1:size(mwireData,1)
                app.procPROG.Message = ['Filtering file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,1))];
                tmpChannel = mwireData(ci,:);
                if wireType == 1 % macro data
                    app.procPROG.Message = ['Resample and notch filter ', num2str(ci), ' out of ',...
                        num2str(size(mwireData,1))];
                    %                     reSampDat = resample(tmpChannel,1,8);
                    reSampDat = downsample(tmpChannel,8);
                    nFs = round(Fs/8);
                    cgrDATAnew = spectrumInterpolation(app, reSampDat', nFs, 60, 3.5, 1);
                    hpFreq = 0.1;
                else % micro data
                    cgrDATAnew = tmpChannel;
                    nFs = Fs;
                    hpFreq = 600;
                    lpFreq = 3000;
                end
                % Both micro and macro high pass filter
                app.procPROG.Message = ['High pass file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,1))];
                [highPASS,~] = highpass(cgrDATAnew , hpFreq , nFs);

                app.procPROG.Message = ['Low pass file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,1))];
                if wireType == 2 % micro data will get low pass filtered
                    [filterTmp,~] = lowpass(highPASS, lpFreq, nFs,...
                        'ImpulseResponse', 'iir', 'Steepness' ,0.8);
                else
                    filterTmp = highPASS;
                end
                filterData(ci,:) = filterTmp;
            end


        end

        function newDat = spectrumInterpolation(~, data, Fs, Fl, neighborsToSample, neighborsToReplace)
            assert(length(data) > 1 && width(data) == 1, "Please transpose your vector, and ensure that you are only passing in 1D data")
            spectrum = fft(data);
            mag = abs(spectrum); % We use the real spectrum to interpolate and remove the powerline noise.
            phase = angle(spectrum);

            binStepSize = length(mag) / Fs;
            nextPowerBin = binStepSize * Fl;
            neighborsToSample = uint32(binStepSize * neighborsToSample);
            neighborsToReplace =  uint32(binStepSize * neighborsToReplace);
            % We have to take care of each end of the spectrum differently.
            % For the first half, the spectra is not reversed.
            nearestPowerlineHarmonic = nextPowerBin;
            binStart = nearestPowerlineHarmonic;

            for i=binStart:nextPowerBin:length(mag)/2
                neighborSamples = mag(i-neighborsToSample:i+neighborsToSample, :);
                neighborhoodAverage = median(neighborSamples);
                for j = 1:width(data)
                    mag(i-neighborsToReplace:i+neighborsToReplace, j) = neighborhoodAverage(j);
                end
            end

            nyquistFrequency = Fs / 2;
            nearestPowerlineHarmonic = mod(nyquistFrequency, Fl); % This gives us the distance to the nyquist frequency starting from the middle.
            binStart = (length(mag)/2) + (nearestPowerlineHarmonic * binStepSize);

            % Now replace the other side

            for i=binStart:nextPowerBin:(length(mag) - nextPowerBin)
                neighborSamples = mag(i-neighborsToSample:i+neighborsToSample, :);
                neighborhoodAverage = median(neighborSamples);
                for j = 1:width(data)
                    mag(i-neighborsToReplace:i+neighborsToReplace, j) = neighborhoodAverage(j);
                end
            end


            % Create a new signal based on euler's formula.
            newDat = mag.*exp(1i.*phase);
            newDat = real(ifft(newDat));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %%%%% CURRENTLY DOES NOT DO ANYTHING 02/16/2023
            currentLOC = which('NLX_Session2Neuro_v17');
            filePARts = split(currentLOC,'\');
            newLOC = join(filePARts(1:numel(filePARts)-1),'\');
            resourceLOC = [newLOC{1}, filesep, 'NLX_IO_Code'];
            addpath(genpath(resourceLOC));

            app.UndoElectrodeLabelsButton.Enable = 'on';
        end

        % Button pushed function: fileLoad
        function fileLoadButtonPushed(app, event)

            conOUT = confirmMessage(app,1);

            switch conOUT
                case "Cancel"
                    return
                case "OK"
                    [app.locsFILE , app.mainLoc] = uigetfile('*.mat');

                    if isempty(app.locsFILE) || ~matches('NWB_Process_Folder_Check.mat' , app.locsFILE)
                        uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                        return
                    else

                        cd(app.mainLoc)
                        load(app.locsFILE , 'nwbFilesLOCS')

                        app.sessLoc.Value = nwbFilesLOCS.sessSAVloc;
                        app.eventLoc.Value = nwbFilesLOCS.eventLOC;
                        app.nwbLoc.Value = nwbFilesLOCS.nwbSAVloc;
                        app.eventLOC = app.eventLoc.Value;
                        app.sessLOC = app.sessLoc.Value;
                        app.nwbLOC = app.nwbLoc.Value ;

                        app.confirm.Enable = 'on';
                        app.resetEV.Enable = 'on';
                        app.resetSE.Enable = 'on';
                        app.resetNWB.Enable  = 'on';

                    end
            end


        end

        % Button pushed function: confirm
        function confirmButtonPushed(app, event)

            % CD to session Folder and load session Files
            cd(app.sessLOC)

            matfindck = dir('*.mat');
            matFindckN = {matfindck.name};

            if isempty(matFindckN) || ~matches('allSessionData.mat',matFindckN)
                uialert(app.UIFigure,'File not found - Run NLX_Event_Finder GUI','Invalid File');
                return
            else
                app.sessFILE = matFindckN{1};
            end

            load(app.sessFILE, "sessionINFo" , "saveSessAll")

            app.sessLOGdat = saveSessAll;
            app.sessINFO = sessionINFo;
            app.AllSESS = 1:length(saveSessAll);
            app.curSESS = app.AllSESS(1);

            if ~isfield(sessionINFo, 'nwbDONE')
                app.sessINFO.nwbDONE = false(length(saveSessAll),1);
                sessionINFo = app.sessINFO;
                save(app.sessFILE,"sessionINFo",'-append')
            else
                app.AllSESS = app.AllSESS(~app.sessINFO.nwbDONE);
                app.curSESS = app.AllSESS(1);
            end

            %%% SAVE new locs =================================
            cd(app.mainLoc)
            nwbFilesLOCS.sessSAVloc = app.sessLoc.Value;
            nwbFilesLOCS.eventLOC   = app.eventLoc.Value;
            nwbFilesLOCS.nwbSAVloc  = app.nwbLoc.Value;

            save(app.locsFILE , 'nwbFilesLOCS')

            %%% When process is complete=======================
            app.sessLoc.Editable = 'off';
            app.eventLoc.Editable = 'off';
            app.confirm.Enable = 'off';
            app.resetEV.Enable = 'off';
            app.resetSE.Enable = 'off';
            app.resetNWB.Enable  = 'off';
            app.START.Enable = 'on';

        end

        % Button pushed function: resetSE
        function resetSEButtonPushed(app, event)
            foldReset(app , 'SE')
        end

        % Button pushed function: resetEV
        function resetEVButtonPushed(app, event)
            foldReset(app , 'EV')
        end

        % Button pushed function: fileNext
        function fileNextButtonPushed(app, event)
            tmpROW = app.curROW;
            app.curROW = app.curROW + 1;

            if app.curROW > length(app.AllSESS)
                app.curROW = tmpROW;
                app.curSESS = app.AllSESS(app.curROW);
                return
            else
                highlightROW(app)
                app.curSESS = app.AllSESS(app.curROW);
            end

            % CONSIDER SCROLL

        end

        % Button pushed function: fileLast
        function fileLastButtonPushed(app, event)
            tmpROW = app.curROW;
            app.curROW = app.curROW - 1;

            if app.curROW < 1
                app.curROW = tmpROW;
                app.curSESS = app.AllSESS(app.curROW);
                return
            else
                highlightROW(app)
                app.curSESS = app.AllSESS(app.curROW);
            end
            % CONSIDER SCROLL

        end

        % Button pushed function: importSEEG
        function importSEEGButtonPushed(app, event)


            uiMICck = uiconfirm(app.UIFigure, "MICRO data collected?",...
                "MICROwire DATA?","Icon","info",'Options',{'YES','NO'},...
                'DefaultOption',1,'CancelOption',2);

            switch uiMICck
                case 'YES'
                    app.microCheck = true;
                case 'NO'
                    app.microCheck = false;
            end

            uiMACck = uiconfirm(app.UIFigure, "MACRO data collected?",...
                "MACROwire DATA?","Icon","info",'Options',{'YES','NO'},...
                'DefaultOption',1,'CancelOption',2);

            switch uiMACck
                case 'YES'
                    app.macroCheck = true;
                case 'NO'
                    app.macroCheck = false;
            end


            chanType = {'micro','macro'};
            confiM = [3,4];
            for ci = 1:2
                ct2use = chanType{ci};

                switch ct2use
                    case 'micro'
                        if app.microCheck
                            confirmMessage(app,confiM(ci))

                            [miFname , miLoc] = uigetfile('*.csv');
                            microLoc = fullfile(miLoc , miFname);
                            microTable = readtable(microLoc);

                            app.channelID.micro = microTable;
                        end
                    case 'macro'
                        %%%%% FUTURE UPDATE - ADD IF/ELSE flag
                        if app.macroCheck
                            confirmMessage(app,confiM(ci))

                            [maFname , maLoc] = uigetfile('*.csv');
                            macroLoc = fullfile(maLoc , maFname);
                            macroTable = readtable(macroLoc);

                            app.channelID.macro = macroTable;
                        end

                end
            end
            channID = app.channelID;
            cd(app.sessLOC)
            save(app.sessFILE,'-append','channID');

            % Activate NWB button
            app.chkNWB.Enable = 'on';
            % Deactivate SEEG label button
            app.importSEEG.Enable = 'off';


        end

        % Button pushed function: chkNWB
        function chkNWBButtonPushed(app, event)

            % Check if on path
            nwbCkt = which('nwbExport');
            % if yes provide message and move on
            if ~isempty(nwbCkt)
                confirmMessage(app, 2)
                app.expNWB.Enable = 'on';
                app.chkNWB.Enable = 'off';
                return
            else
                % if no - ask if installed
                request = uiconfirm(app.UIFigure, "IS NWB Installed??",...
                    "NWB INSTALLATION",'Options',{'YES','NO'});

                switch request
                    case 'YES'
                        % if yes - located folder
                        locFOLD = confirmMessage(app,5);
                        switch locFOLD
                            case{'OK','Cancel'}
                                nwbFOLD = uigetdir();
                                addpath(genpath(nwbFOLD));
                        end

                    case 'NO'
                        matLOC = confirmMessage(app,6);
                        switch matLOC
                            case{'OK','Cancel'}
                                matFOLD = uigetdir();
                                cd(matFOLD);
                                app.prog = uiprogressdlg(app.UIFigure, "Message","Installing NWB",...
                                    'Indeterminate','on');
                                !git clone https://github.com/NeurodataWithoutBorders/matnwb.git

                                nwbdirF1 = dir();
                                nwbdirF2 = {nwbdirF1.name};
                                nwbdirF3 = nwbdirF2(~ismember(nwbdirF2, {'.','..'}));

                                nwbdirF4 = nwbdirF3{contains(nwbdirF3,'matnwb')};
                                nwbdirF5 = [matFOLD , filesep , nwbdirF4];

                                addpath(genpath(nwbdirF5))

                                close(app.prog)

                        end
                end

            end
            app.chkNWB.Enable = 'off';
            app.expNWB.Enable = 'on';


        end

        % Button pushed function: expNWB
        function expNWBButtonPushed(app, event)

            % Run NWB export and save in NWB folder
            % Open Fields in subject / session boxes
            app.SubjectInfoP.Enable = 'on';
            app.SessionInfoP.Enable = 'on';
            app.confirmINFO.Enable = 'on';
            app.confirmINFO.Visible = 'on';

        end

        % Button pushed function: resetNWB
        function resetNWBButtonPushed(app, event)

            foldReset(app , 'NWB')

        end

        % Value changed function: confirmINFO
        function confirmINFOValueChanged(app, event)
            value = app.confirmINFO.Value;

            app.SubjectInfoP.Enable = 'off';
            app.SessionInfoP.Enable = 'off';

            if strcmp(value,'YES')

                app.procPROG = uiprogressdlg(app.UIFigure,'Title','Creating and Saving NWB...',...
                    'Indeterminate','on');

                curSess = app.sessLOGdat{app.curSESS}; % change to session
                % Check if it is behavior or resting state
                cd(app.nwbLOC)

                % From data
                identifier = app.sessINFO.SUBid{1};
                timeStamP = curSess.SessionInfo.ts(curSess.StartIndex);
                sessionstarttime = datetime(timeStamP/1000000,'ConvertFrom',...
                    'posixtime','TimeZone','America/Denver');

                generateCore();

                app.procPROG.Message = 'Core generated';

                % Create NWB with session and subject info - Acquistion
                nwbA = NwbFile( ...
                    'session_description', 'EMU patient',...
                    'identifier', [identifier ,'_', num2str(app.curSESS)], ... %
                    'session_start_time', sessionstarttime, ...
                    'general_experimenter', app.ExperimenterDropDown.Value, ...
                    'general_session_id', app.SessionIDDropDown.Value, ...
                    'general_institution', 'UC Anschutz');

                nwbP = NwbFile( ...
                    'session_description', 'EMU patient',...
                    'identifier', [identifier ,'_', num2str(app.curSESS)], ... %
                    'session_start_time', sessionstarttime, ...
                    'general_experimenter', app.ExperimenterDropDown.Value, ...
                    'general_session_id', app.SessionIDDropDown.Value, ...
                    'general_institution', 'UC Anschutz');

                subject = types.core.Subject(...
                    'subject_id', identifier, ...
                    'age', ['P',num2str(app.SubAgeEditField.Value),'Y'], ...
                    'sex',app.SubSexDropDown.Value);

                nwbA.general_subject = subject;
                nwbP.general_subject = subject;

                app.procPROG.Message = 'Subject added';

                device = types.core.Device(...
                    'description', 'Atlas', ...
                    'manufacturer', 'Neuralynx');
                device_name = 'NLX';

                nwbA.general_devices.set(device_name, device);
                nwbP.general_devices.set(device_name, device);

                device_link = types.untyped.SoftLink(['/general/devices/' device_name]);

                app.procPROG.Message = 'Device added';

                % Define Number of Depth electrodes and
                % microwires

                % Load in microwire table
                if app.microCheck

                    % Create similar table to MW - check number of wires
                    mwNUM = unique(app.channelID.micro.depEnum);
                    depEnum = zeros(length(mwNUM),1);
                    startChan = zeros(length(mwNUM),1);
                    stopChan = zeros(length(mwNUM),1);
                    eleDiam = zeros(length(mwNUM),1);
                    eleLen = zeros(length(mwNUM),1);
                    shortN = cell(length(mwNUM),1);
                    longN = cell(length(mwNUM),1);
                    hemi = cell(length(mwNUM),1);
                    numChan = zeros(length(mwNUM),1);
                    eleModel = cell(length(mwNUM),1);
                    for dpi = 1:length(mwNUM)
                        miTABo = app.channelID.micro(app.channelID.micro.depEnum == mwNUM(dpi),:);
                        depEnum(dpi,1) = unique(miTABo.depEnum);
                        startChan(dpi,1) = miTABo.chanNum(1);
                        stopChan(dpi,1) = miTABo.chanNum(height(miTABo));
                        shortN(dpi,1) = unique(miTABo.shortN);
                        longN(dpi,1) = unique(miTABo.longN);
                        hemi(dpi,1) = {miTABo.shortN{1}(1)};
                        numChan(dpi,1) = height(miTABo);
                        eleModel(dpi,1) = unique(miTABo.eleModel);
                        eleDiam(dpi,1) = NaN;
                        eleLen(dpi,1) = NaN;
                    end
                    elecR = repmat({'MI'},length(mwNUM),1);
                    miTAB = table(depEnum,startChan,stopChan,shortN,longN,hemi,numChan,eleModel,eleDiam,eleLen,elecR);
                else
                    variables = {'depEnum', 'startChan', 'stopChan', 'shortN',...
                        'longN', 'hemi', 'numChan', 'eleModel','eleDiam','eleLen','elecR'};
                    miTAB = cell2table(cell(0, length(variables)), 'VariableNames', variables);
                end

                if app.macroCheck
                    maTAB = app.channelID.macro;
                    maTAB.elecR = repmat({'MA'},height(maTAB),1);
                else
                    variables = {'depEnum', 'startChan', 'stopChan', 'shortN',...
                        'longN', 'hemi', 'numChan', 'eleModel','eleDiam','eleLen','elecR'};
                    maTAB = cell2table(cell(0, length(variables)), 'VariableNames', variables);
                end

                allTAB = [miTAB ; maTAB];

                app.procPROG.Message = 'Electrode table initiated';

                % Populate table
                % Add short and long name to columns -
                allIDEtab = table;
                for iDE = 1:height(allTAB)

                    tmpWire = num2str(allTAB.depEnum(iDE));
                    mimatyp = allTAB.elecR{iDE};
                    if strcmp(mimatyp,'MI')
                        descriP = ['electrode group for microwires on DE_' tmpWire];
                    else
                        descriP = ['electrode group for macrocontacts on DE_' tmpWire];
                    end
                    % Long name
                    brainArLocation = allTAB.longN{iDE};
                    % Short name
                    brainArSN = allTAB.shortN{iDE};
                    % hemisphere
                    hemiBA = allTAB.shortN{iDE}(1);
                    % Number of channels per wire
                    nchannels_per_DE = allTAB.numChan(iDE);

                    group_name = [mimatyp, '_DE_' tmpWire];

                    nwbA.general_extracellular_ephys.set(group_name, ...
                        types.core.ElectrodeGroup( ...
                        'description', descriP, ...
                        'location', brainArLocation, ...
                        'device', device_link));

                    nwbP.general_extracellular_ephys.set(group_name, ...
                        types.core.ElectrodeGroup( ...
                        'description', descriP, ...
                        'location', brainArLocation, ...
                        'device', device_link));

                    group_object_view = types.untyped.ObjectView( ...
                        ['/general/extracellular_ephys/' group_name]); % Currently as wire change to micro and macro as devices

                    xcoord = zeros(nchannels_per_DE,1);
                    ycoord = zeros(nchannels_per_DE,1);
                    zcoord = zeros(nchannels_per_DE,1);
                    imp = zeros(nchannels_per_DE,1);
                    location = repmat({brainArLocation},nchannels_per_DE,1);
                    filtering = cell(nchannels_per_DE,1); %%%%% IF MACRO 0.1 to 1000 kHz - filter
                    shortBAn = repmat({brainArSN},nchannels_per_DE,1);
                    hemisph = repmat({hemiBA},nchannels_per_DE,1);
                    wireID = repmat(allTAB.depEnum(iDE),nchannels_per_DE,1);
                    channID = zeros(nchannels_per_DE,1);
                    group = [];% Wire ID
                    label = cell(nchannels_per_DE,1);% Chan ID

                    eleARRAY = allTAB.startChan(iDE):allTAB.stopChan(iDE);
                    for ielec = 1:nchannels_per_DE
                        xcoord(ielec) = NaN;
                        ycoord(ielec) = NaN;
                        zcoord(ielec) = NaN;
                        imp(ielec) = NaN;
                        group = [group ; group_object_view]; %#ok<AGROW>
                        if strcmp(mimatyp,'MI')
                            filtering{ielec} = '300-3000';
                        else
                            filtering{ielec} = '0.1-1000';
                        end
                        channID(ielec) = eleARRAY(ielec);
                        label{ielec} = [mimatyp,'_D',tmpWire,'_C',num2str(eleARRAY(ielec))];
                    end

                    % Create table or each iDE
                    tbl = table(xcoord, ycoord, zcoord, imp, location, ...
                        filtering, channID, shortBAn, hemisph, ...
                        wireID, group,label);

                    allIDEtab = [allIDEtab ; tbl]; %#ok<AGROW>

                end

                app.procPROG.Message = 'Electrode table created';

                allIDEtab.Properties.VariableDescriptions = repmat({''}, 1, width(allIDEtab));

                % 1. Create REQUIRED VectorData objects
                loc_col = types.hdmf_common.VectorData( ...
                    'description', 'electrode location', ...
                    'data', allIDEtab.location ...
                    );

                grp = allIDEtab.group;
                if isrow(grp); grp = grp.'; end
                group_col = types.hdmf_common.VectorData( ...
                    'description', 'electrode group', ...
                    'data', grp ...
                    );

                % 2. Build ElectrodesTable with COLNAMES passed immediately
                %    (This prevents the constructor assertion error)
                N = height(allIDEtab);
                electrodes = types.core.ElectrodesTable( ...
                    'description', 'all electrodes', ...
                    'id', types.hdmf_common.ElementIdentifiers('data', int64((0:N-1)')), ...
                    'location', loc_col, ...
                    'group', group_col, ...
                    'colnames', {'location', 'group'} ... % <--- CRITICAL FIX HERE
                    );

                % 3. CRITICAL: Manually FORCE group/location into vectordata
                % (In case the constructor didn't do it automatically)
                if ~electrodes.vectordata.isKey('location')
                    electrodes.vectordata.set('location', loc_col);
                end
                if ~electrodes.vectordata.isKey('group')
                    electrodes.vectordata.set('group', group_col);
                end

                % ---- Add your other columns (optional but recommended) ----
                electrodes.vectordata.set('x', types.hdmf_common.VectorData('description','x', 'data', allIDEtab.xcoord));
                electrodes.vectordata.set('y', types.hdmf_common.VectorData('description','y', 'data', allIDEtab.ycoord));
                electrodes.vectordata.set('z', types.hdmf_common.VectorData('description','z', 'data', allIDEtab.zcoord));
                electrodes.vectordata.set('imp', types.hdmf_common.VectorData('description','impedance', 'data', allIDEtab.imp));
                electrodes.vectordata.set('filtering', types.hdmf_common.VectorData('description','filtering', 'data', allIDEtab.filtering));
                electrodes.vectordata.set('label', types.hdmf_common.VectorData('description','label', 'data', allIDEtab.label));

                % Your custom metadata columns
                electrodes.vectordata.set('channID', types.hdmf_common.VectorData('description','channel id', 'data', allIDEtab.channID));
                electrodes.vectordata.set('shortBAn', types.hdmf_common.VectorData('description','short brain area', 'data', allIDEtab.shortBAn));
                electrodes.vectordata.set('hemisph', types.hdmf_common.VectorData('description','hemisphere', 'data', allIDEtab.hemisph));
                electrodes.vectordata.set('wireID', types.hdmf_common.VectorData('description','wire id', 'data', allIDEtab.wireID));

                % MANDATORY: Tell the table which columns exist
                electrodes.colnames = electrodes.vectordata.keys;

                % Assign
                nwbA.general_extracellular_ephys_electrodes = electrodes;
                nwbP.general_extracellular_ephys_electrodes = electrodes;

                electrodes_object_view = types.untyped.ObjectView( ...
                    '/general/extracellular_ephys/electrodes');

                % Add timestamps
                if app.microCheck

                    mifieldLOC = find(contains(allIDEtab.label,'MI'))-1;

                    electrode_table_region1 = types.hdmf_common.DynamicTableRegion( ...
                        'table', electrodes_object_view, ...
                        'description', 'all micro electrodes', ...
                        'data', mifieldLOC);

                    % Get sampling frequency, data (unscaled, unfurled and
                    % filtered), adbitconvert, int16

                    % SAVE UNFILTERED in acquisition
                    % SAVE FILTERED in processing / downsample MACRO
                    % SAVE out Time sample
                    % Input start time and duration from sessionINFO
                    [microDAT , micSF , miADbitval , MIallTS] = CSCLoader(app , allIDEtab , 'MI');

                    % Filter data
                    [microFilter , micronFs] = mmfilterFun(app , microDAT , micSF, 2);

                    app.procPROG.Message = 'micro data processed';

                    cd(app.nwbLOC)

                    midesCripA = ['Fs = ', num2str(micSF) , ' :'...
                        'Micro data that needs to be converted' ...
                        'to volts with conversion [samples .*data_conversion] - from int16 to float'];

                    electrical_series_microA = types.core.ElectricalSeries( ...
                        'data', microDAT, ...  %
                        'electrodes', electrode_table_region1, ...
                        'data_unit', 'volts', ...
                        'data_conversion', miADbitval, ...
                        'timestamps', MIallTS, ...
                        'description', midesCripA);

                    midesCripP = ['Fs = ', num2str(micronFs) , ' :'...
                        'Micro data that needs to be converted' ...
                        'to volts with conversion [samples .*data_conversion] - from int16 to float'];

                    electrical_series_microP = types.core.ElectricalSeries( ...
                        'data', microFilter, ...  %
                        'electrodes', electrode_table_region1, ...
                        'data_unit', 'volts', ...
                        'data_conversion', miADbitval, ...
                        'timestamps', MIallTS, ...
                        'description', midesCripP);

                end

                if app.macroCheck

                    mafieldLOC = find(contains(allIDEtab.label,'MA'))-1;

                    electrode_table_region2 = types.hdmf_common.DynamicTableRegion( ...
                        'table', electrodes_object_view, ...
                        'description', 'all macro electrodes', ...
                        'data', mafieldLOC);

                    [macroDAT , macSF , maADbitval , MAallTS] = CSCLoader(app , allIDEtab , 'MA');

                    % Filter data
                    [macroFilter , macronFs] = mmfilterFun(app , macroDAT , macSF, 1);

                    app.procPROG.Message = 'macro data processed';

                    cd(app.nwbLOC)

                    madesCripA = ['Fs = ', num2str(macSF) , ' :'...
                        'Macro data that needs to be converted' ...
                        'to volts with conversion [samples .*data_conversion] - from int16 to float'];

                    electrical_series_macroA = types.core.ElectricalSeries( ...
                        'data', macroDAT, ...  % Number of wires in ROW randn(12, 3000) microDAT
                        'electrodes', electrode_table_region2, ...  %%%%%%% CHANGED from electrodes
                        'data_unit', 'volts', ...
                        'data_conversion', maADbitval, ...
                        'timestamps', MAallTS, ...
                        'description', madesCripA);

                    madesCripP = ['Fs = ', num2str(macronFs) , ' :'...
                        'Macro data that needs to be converted' ...
                        'to volts with conversion [samples .*data_conversion] - from int16 to float'];

                    electrical_series_macroP = types.core.ElectricalSeries( ...
                        'data', macroFilter, ...  % Number of wires in ROW randn(12, 3000) microDAT
                        'electrodes', electrode_table_region2, ...  %%%%%%% CHANGED from electrodes
                        'data_unit', 'volts', ...
                        'data_conversion', maADbitval, ...
                        'timestamps', MAallTS, ...  %%%%%%%%%%% need new time
                        'description', madesCripP);
                end

                % Micro data
                if app.microCheck
                    nwbA.acquisition.set('MicroWireSeries', electrical_series_microA);
                end
                % Macro data
                if app.macroCheck
                    nwbA.acquisition.set('MacroWireSeries', electrical_series_macroA);
                end

                lfp = types.core.LFP();

                % Micro data
                if app.microCheck
                    lfp.electricalseries.set('MicroWireSeries', electrical_series_microP);
                end

                % Macro data
                if app.macroCheck
                    lfp.electricalseries.set('MacroWireSeries', electrical_series_macroP);
                end

                ecephys_module = types.core.ProcessingModule(...
                    'description', 'extracellular electrophysiology');

                ecephys_module.nwbdatainterface.set('LFP', lfp);
                nwbP.processing.set('ecephys', ecephys_module);

                app.procPROG.Message = 'Electrode Series added';

                sessTYPE = app.UITable.Data.SessID{app.curROW};

                switch sessTYPE
                    case {'Resting State','Resting state'}

                        allTStamps = app.sessLOGdat{app.curSESS}.SessionInfo.ts; % change to session
                        indStart = allTStamps(app.sessLOGdat{app.curSESS}.StartIndex); % change to session
                        indStop = allTStamps(app.sessLOGdat{app.curSESS}.StopIndex); % change to session
                        idNUMS = [0 , 1];
                        behNUMS = [0 , 0];

                        trials = types.core.TimeIntervals( ...
                            'colnames', {'start_time', 'stop_time', 'correct'}, ...
                            'description', 'trial data and properties', ...
                            'id', types.hdmf_common.ElementIdentifiers('data', idNUMS), ...
                            'start_time', types.hdmf_common.VectorData('data', indStart, ...
                            'description','start time of trial'), ...
                            'stop_time', types.hdmf_common.VectorData('data', indStop, ...
                            'description','end of each trial'), ...
                            'correct', types.hdmf_common.VectorData('data', behNUMS, ...
                            'description', 'whether the trial was correct'));

                        nwbA.intervals_trials = trials;
                        nwbP.intervals_trials = trials;

                    case 'Behavior'
                        allTStamps = app.sessLOGdat{app.curSESS}.SessionInfo.ts; % change to session
                        allTSids = app.sessLOGdat{app.curSESS}.SessionInfo.tInfo; % change to session

                        %%%%%% CHECK IF START VALUE IS 'STARTING'...

                        indStartVal = app.sessLOGdat{app.curSESS}.StartIndex; % change to session
                        indStopVal = app.sessLOGdat{app.curSESS}.StopIndex; % change to session

                        % Check start

                        stimulusEvents = allTStamps(indStartVal:indStopVal);

                        % Placeholder for event strings
                        eventSTRs = allTSids(indStartVal:indStopVal);

                        nwbEvents = types.core.AnnotationSeries('data', eventSTRs, ...
                            'timestamps', stimulusEvents, ...
                            'description', 'TTLs');

                        nwbA.acquisition.set('events', nwbEvents)
                        nwbP.acquisition.set('events', nwbEvents)

                end

                nwbNAME_A = [app.sessINFO.SUBid{1},'_','Session_',num2str(app.curSESS),'_raw.nwb'];
                nwbNAME_P = [app.sessINFO.SUBid{1},'_','Session_',num2str(app.curSESS),'_filter.nwb'];

                nwbExport(nwbA, nwbNAME_A)
                nwbExport(nwbP, nwbNAME_P)

                % Update Session file
                cd(app.sessLOC)
                nwbINFO.update = 1;
                nwbINFO.dateU = char(datetime);
                nwbINFO.fileLOC = app.nwbLOC;
                nwbINFO.fileNameA = nwbNAME_A;
                nwbINFO.fileNameP = nwbNAME_P;
                save(app.sessFILE,"nwbINFO" ,'-append')
                cd(app.nwbLOC)

                close(app.procPROG)

                % Reset
                app.SubjectInfoP.Enable = 'on';
                app.SessionInfoP.Enable = 'on';
                app.confirmINFO.Value = 'NO';

                % Remove Row from table
                tmpTable = app.UITable.Data;
                tmpTable(app.curROW,:) = [];
                app.sessINFO.nwbDONE(app.curSESS) = true; % Change to session

                % Clean up AllSESS % if CurSESS 4 and all SESS 8 =
                % [1 2 3 5 6 7 8] - use CurSESS = ALLSESS(curROW)
                app.AllSESS(app.curROW) = [];

                if app.curROW > length(app.AllSESS)
                    app.curROW = length(app.AllSESS);
                end

                if isempty(app.AllSESS)
                    app.curSESS = 0;
                else
                    app.curSESS = app.AllSESS(app.curROW);
                end

                % Update Table
                app.UITable.Data = tmpTable;
                % save session file
                cd(app.sessLOC)
                sessionINFo = app.sessINFO;
                save(app.sessFILE,"sessionINFo" ,'-append')

            else
                app.SubjectInfoP.Enable = 'on';
                app.SessionInfoP.Enable = 'on';
            end

        end

        % Button pushed function: START
        function STARTButtonPushed(app, event)
            % UI WAIT
            app.prog = uiprogressdlg(app.UIFigure, "Message","Setting up Table",...
                'Indeterminate','on');

            % Save new information:
            cd(app.sessLOC)
            load(app.sessFILE,"sessionINFo")
            sessionINFo.nwbLOC = app.nwbLOC;
            sessionINFo.eventLOC = app.eventLOC;
            sessionINFo.sessiLOC = app.sessLOC;

            app.sessINFO = sessionINFo;

            save(app.sessFILE,"sessionINFo" ,'-append')

            % Load in first panel

            % Activate Session Panel
            app.SPpanel.Enable = 'on';

            % Check if Data table is available
            cd(app.sessLOC)
            datTABchk = matfile(app.sessFILE);
            datTABids = whos(datTABchk);
            datTABnames = {datTABids.name};
            datTABflag = ismember("sessINFOtable",datTABnames);

            if ~datTABflag
                % Populate Session Table
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                [rows,cols] = size(app.sessLOGdat);
                if cols > rows
                    sessLOGdat_T = transpose(app.sessLOGdat);
                else
                    sessLOGdat_T = app.sessLOGdat;
                end


                sessNUM = transpose(1:length(sessLOGdat_T)); % NEW
                nevFileN = cell(size(sessLOGdat_T)); % NEW
                sessID = cell(size(sessLOGdat_T));
                dateREC = cell(size(sessLOGdat_T));
                timeREC = cell(size(sessLOGdat_T));
                startTime = zeros(size(sessLOGdat_T));
                startInd = zeros(size(sessLOGdat_T));
                cscID = cell(size(sessLOGdat_T));
                offsetMS = zeros(size(sessLOGdat_T));
                durMins = zeros(size(sessLOGdat_T));
                numEventS = zeros(size(sessLOGdat_T));

                for ti = 1:length(app.sessLOGdat)
                    % Row 1: Session Number
                    % Row 2: Event NEV file name
                    % Row 3: SessionID = SessType
                    % Row 4: Date recorded = DateRec
                    % Row 5: Time recorded = DateRec
                    % Row 6: StartIndex = StartIndex
                    % Row 7: StartTime = SessionInfo.ts(StartIndex)
                    % Row 8: CSC ID = ?
                    % Row 9: Duration offset in milliseconds
                    % Row 10: Duration of session in minutes
                    % Row 11: Number of Events
                    sessID{ti} = app.sessLOGdat{ti}.SessType;
                    dateREC{ti} = datestr(app.sessLOGdat{ti}.DateRec,'mm/dd/yyyy');
                    timeREC{ti} = datestr(app.sessLOGdat{ti}.DateRec,"HH:MM:SS");
                    startInd(ti) = app.sessLOGdat{ti}.StartIndex;
                    startTime(ti) = app.sessLOGdat{ti}.SessionInfo.ts(startInd(ti));

                    tmpHeader = app.sessLOGdat{ti}.SessionInfo.header;
                    tmpTEXT = split(tmpHeader(6),'\');

                    nevFileN{ti} = tmpTEXT{length(tmpTEXT)};

                    % Identify CSC file
                    app.prog.Message = ['Working on file ', num2str(ti), ' of file ',...
                        num2str(length(app.sessLOGdat)) ];

                    [cscNUM , offSet] = findCSCid(app , startTime(ti));

                    cscID{ti} = cscNUM;
                    offsetMS(ti) = int16(offSet);

                    startT = datetime(app.sessLOGdat{ti}.StartTime/1000000,...
                        'ConvertFrom','posixtime','TimeZone','America/Denver');
                    stopT = datetime(app.sessLOGdat{ti}.StopTime/1000000,...
                        'ConvertFrom','posixtime','TimeZone','America/Denver');
                    durMins(ti) = int16(ceil(minutes(stopT - startT)));

                    numEventS(ti) = app.sessLOGdat{ti}.StopIndex - app.sessLOGdat{ti}.StartIndex;

                end

                close(app.prog)

                datTable = table(sessNUM, nevFileN , sessID, dateREC, timeREC, startTime, startInd,...
                    cscID, offsetMS, durMins, numEventS, 'VariableNames',app.UITable.ColumnName);

                % Check NWB done
                datTable2 = datTable(~app.sessINFO.nwbDONE,:);

                % SAVE TABLE WITH INFO to SESSION app and CHECK for %
                % 08/03/2021
                sessINFOtable = datTable2;
                cd(app.sessLOC)
                save(app.sessFILE,"sessINFOtable",'-append');
                app.UITable.Data = datTable2;
            else
                load(app.sessFILE,"sessINFOtable")
                updateTable = sessINFOtable(~app.sessINFO.nwbDONE,:);
                app.UITable.Data = updateTable;
                close(app.prog)
            end


            % Open up Neuro Processes
            % Check if data are present in .mat file
            cd(app.sessLOC)
            exampleObject = matfile(app.sessFILE);

            % MACRO AND MICRO
            varlist = who(exampleObject);
            seFcheck = contains(varlist, 'channID');

            % If present - load it in but leave button off
            if any(seFcheck)
                load(app.sessFILE,"channID")
                app.channelID = channID;

                uiMICck = uiconfirm(app.UIFigure, "MICRO data collected?",...
                    "MICROwire DATA?","Icon","info",'Options',{'YES','NO'},...
                    'DefaultOption',1,'CancelOption',2);

                switch uiMICck
                    case 'YES'
                        app.microCheck = true;
                    case 'NO'
                        app.microCheck = false;
                end

                uiMACck = uiconfirm(app.UIFigure, "MACRO data collected?",...
                    "MACROwire DATA?","Icon","info",'Options',{'YES','NO'},...
                    'DefaultOption',1,'CancelOption',2);

                switch uiMACck
                    case 'YES'
                        app.macroCheck = true;
                    case 'NO'
                        app.macroCheck = false;
                end


            else % If not present - have user navigate and load
                app.importSEEG.Enable = 'on';
            end

            % Open up Next file
            app.fileNext.Enable = 'on';
            app.fileLast.Enable = 'on';

            % Turn on NWB
            app.chkNWB.Enable = 'on';

            % Highlight current row
            app.curROW = 1;
            highlightROW(app);

            app.START.Enable = 'off';
        end

        % Button pushed function: UndoElectrodeLabelsButton
        function UndoElectrodeLabelsButtonPushed(app, event)

            % Remove Channel labels from struct
            cd(app.sessLOC);
            load(app.sessFILE);
            exampleObject = matfile(app.sessFILE);
            varlist = who(exampleObject);
            seFcheck = varlist(~contains(varlist, 'channID'));
            save(app.sessFILE,seFcheck{:});
            %             save(app.sessFILE,'-append','channID');
            % If not present - have user navigate and load
            app.importSEEG.Enable = 'on';
            % Release Import channel button


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FOpanel = uipanel(app.UIFigure);
            app.FOpanel.Title = 'FILE OPTIONS';
            app.fileLoad = uibutton(app.FOpanel, 'push');
            app.fileLoad.ButtonPushedFcn = createCallbackFcn(app, @fileLoadButtonPushed, true);
            app.fileLoad.Icon = 'FolderCREATE.png';
            app.fileLoad.Text = '';
            app.fileNext = uibutton(app.FOpanel, 'push');
            app.fileNext.ButtonPushedFcn = createCallbackFcn(app, @fileNextButtonPushed, true);
            app.fileNext.Enable = 'off';
            app.fileNext.Text = 'Next File';
            app.fileLast = uibutton(app.FOpanel, 'push');
            app.fileLast.ButtonPushedFcn = createCallbackFcn(app, @fileLastButtonPushed, true);
            app.fileLast.Enable = 'off';
            app.fileLast.Text = 'Last File';
            app.DLpanel = uipanel(app.UIFigure);
            app.DLpanel.Title = 'DIRECTORY LOCATIONS';
            app.EVENTSEditFieldLabel = uilabel(app.DLpanel);
            app.EVENTSEditFieldLabel.Text = 'EVENTS';
            app.eventLoc = uieditfield(app.DLpanel, 'text');
            app.SESSIONEditFieldLabel = uilabel(app.DLpanel);
            app.SESSIONEditFieldLabel.Text = 'SESSION';
            app.sessLoc = uieditfield(app.DLpanel, 'text');
            app.confirm = uibutton(app.DLpanel, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirm.Enable = 'off';
            app.confirm.Text = {'CONFIRM'; 'Directories'; ''};
            app.resetEV = uibutton(app.DLpanel, 'push');
            app.resetEV.ButtonPushedFcn = createCallbackFcn(app, @resetEVButtonPushed, true);
            app.resetEV.Enable = 'off';
            app.resetEV.Text = 'Reset';
            app.resetSE = uibutton(app.DLpanel, 'push');
            app.resetSE.ButtonPushedFcn = createCallbackFcn(app, @resetSEButtonPushed, true);
            app.resetSE.Enable = 'off';
            app.resetSE.Text = 'Reset';
            app.NWBLabel = uilabel(app.DLpanel);
            app.NWBLabel.Text = 'NWB';
            app.nwbLoc = uieditfield(app.DLpanel, 'text');
            app.resetNWB = uibutton(app.DLpanel, 'push');
            app.resetNWB.ButtonPushedFcn = createCallbackFcn(app, @resetNWBButtonPushed, true);
            app.resetNWB.Enable = 'off';
            app.resetNWB.Text = 'Reset';
            app.START = uibutton(app.DLpanel, 'push');
            app.START.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.START.Enable = 'off';
            app.START.Text = {'START'; 'Process'};
            app.SPpanel = uipanel(app.UIFigure);
            app.SPpanel.Enable = 'off';
            app.SPpanel.Title = 'SESSION PROCESSING';
            app.UITable = uitable(app.SPpanel);
            app.UITable.ColumnName = {'Sess#'; 'EventNEV'; 'SessID'; 'RecDate'; 'RecTime'; 'StartT'; 'StartI'; 'CSC ID'; 'offSET'; 'DurMins'; 'Event#'};
            app.UITable.RowName = {};
            app.step2 = uilabel(app.SPpanel);
            app.step2.Text = 'STEP 2:';
            app.step3 = uilabel(app.SPpanel);
            app.step3.Text = 'STEP 3:';
            app.importSEEG = uibutton(app.SPpanel, 'push');
            app.importSEEG.ButtonPushedFcn = createCallbackFcn(app, @importSEEGButtonPushed, true);
            app.importSEEG.Enable = 'off';
            app.importSEEG.Text = 'Import Neuro Labels';
            app.expNWB = uibutton(app.SPpanel, 'push');
            app.expNWB.ButtonPushedFcn = createCallbackFcn(app, @expNWBButtonPushed, true);
            app.expNWB.Enable = 'off';
            app.expNWB.Text = 'Export to NWB';
            app.chkNWB = uibutton(app.SPpanel, 'push');
            app.chkNWB.ButtonPushedFcn = createCallbackFcn(app, @chkNWBButtonPushed, true);
            app.chkNWB.Enable = 'off';
            app.chkNWB.Text = 'Check for NWB';
            app.step1 = uilabel(app.SPpanel);
            app.step1.Text = 'STEP 1:';
            app.SessionInfoP = uipanel(app.UIFigure);
            app.SessionInfoP.Enable = 'off';
            app.SessionInfoP.Title = 'Session Info';
            app.SessionIDDropDownLabel = uilabel(app.SessionInfoP);
            app.SessionIDDropDownLabel.Text = 'SessionID';
            app.SessionIDDropDown = uidropdown(app.SessionInfoP);
            app.SessionIDDropDown.Items = {'Training', 'NewOld-Learn', 'NewOld-Recog', 'FaceEmotion', 'ReferenceTest', 'LossAversion1', 'Stroop', 'RCP-NatSpeech', 'RCP-Nback prac', 'RCP-Nback real', 'RCP-ToneTap R', 'RCP-ToneTap L', 'RCP-Diadochn', 'RCP-FingerTap', 'RCP-Naming', 'RCP-Reach', 'RCP-VowelSpace'};
            app.SessionIDDropDown.Value = 'Training';
            app.ExperimenterDropDownLabel = uilabel(app.SessionInfoP);
            app.ExperimenterDropDownLabel.Text = 'Experimenter';
            app.ExperimenterDropDown = uidropdown(app.SessionInfoP);
            app.ExperimenterDropDown.Items = {'A. Tekriwal', 'R. Tien', 'L. Hirt', 'J. Thompson', 'K. Tyner', 'J. Platt'};
            app.ExperimenterDropDown.Value = 'A. Tekriwal';
            app.SubjectInfoP = uipanel(app.UIFigure);
            app.SubjectInfoP.Enable = 'off';
            app.SubjectInfoP.Title = 'Subject Info';
            app.SubAgeEditFieldLabel = uilabel(app.SubjectInfoP);
            app.SubAgeEditFieldLabel.Text = 'Sub. Age';
            app.SubAgeEditField = uieditfield(app.SubjectInfoP, 'numeric');
            app.SubSexDropDownLabel = uilabel(app.SubjectInfoP);
            app.SubSexDropDownLabel.Text = 'Sub. Sex';
            app.SubSexDropDown = uidropdown(app.SubjectInfoP);
            app.SubSexDropDown.Items = {'F', 'M'};
            app.SubSexDropDown.Value = 'F';
            app.CONFIRMINFOSwitchLabel = uilabel(app.UIFigure);
            app.CONFIRMINFOSwitchLabel.Visible = 'off';
            app.CONFIRMINFOSwitchLabel.Text = 'CONFIRM INFO';
            app.confirmINFO = uiswitch(app.UIFigure, 'slider');
            app.confirmINFO.Items = {'NO', 'YES'};
            app.confirmINFO.ValueChangedFcn = createCallbackFcn(app, @confirmINFOValueChanged, true);
            app.confirmINFO.Enable = 'off';
            app.confirmINFO.Visible = 'off';
            app.confirmINFO.Value = 'NO';
            app.ERRORCheckingPanel = uipanel(app.UIFigure);
            app.ERRORCheckingPanel.Title = 'ERROR - Checking';
            app.UndoElectrodeLabelsButton = uibutton(app.ERRORCheckingPanel, 'push');
            app.UndoElectrodeLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @UndoElectrodeLabelsButtonPushed, true);
            app.UndoElectrodeLabelsButton.Text = 'Undo Electrode Labels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_Session2Neuro_v18

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end